classdef ChooseTracks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CheckBox1                   matlab.ui.control.CheckBox
        CheckBox2                   matlab.ui.control.CheckBox
        CheckBox3                   matlab.ui.control.CheckBox
        CheckBox4                   matlab.ui.control.CheckBox
        CheckBox5                   matlab.ui.control.CheckBox
        CheckBox6                   matlab.ui.control.CheckBox
        CheckBox7                   matlab.ui.control.CheckBox
        CheckBox8                   matlab.ui.control.CheckBox
        CheckBox9                   matlab.ui.control.CheckBox
        FinishedButton              matlab.ui.control.Button
        ChooseTrackcategoriesLabel  matlab.ui.control.Label
        CheckBox10                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp = MainApp;
            list = MainApp.ClassDropDown.Items;
            app.CheckBox1.Text = list{1};
            app.CheckBox2.Text = list{2};
            app.CheckBox3.Text = list{3};
            app.CheckBox4.Text = list{4};
            app.CheckBox5.Text = list{5};
            app.CheckBox6.Text = list{6};
            app.CheckBox7.Text = list{7};
            app.CheckBox8.Text = list{8};
            app.CheckBox9.Text = list{9};
            app.CheckBox10.Text = list{10};
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % Get values from checkboxes
            checked(1) = app.CheckBox1.Value;
            checked(2) = app.CheckBox2.Value;
            checked(3) = app.CheckBox3.Value;
            checked(4) = app.CheckBox4.Value;
            checked(5) = app.CheckBox5.Value;
            checked(6) = app.CheckBox6.Value;
            checked(7) = app.CheckBox7.Value;
            checked(8) = app.CheckBox8.Value;
            checked(9) = app.CheckBox9.Value;
            checked(10) = app.CheckBox10.Value;
            
            %Now only display tracks that have one of the checked
            %classifications in the track dropdown
            
            %List of all track numbers
            track_nums = 1:length(app.CallingApp.dets.trackID);
            track_IDs = app.CallingApp.dets.trackID;
            for i = 1:length(checked)
                if ~checked(i)
                    idx = track_IDs == i-1;
                    %Delete track numbers
                    track_nums(idx) = [];
                    
                    %Delete Track ID labels from list as well
                    track_IDs(idx) = [];
                end
            end
            app.CallingApp.TrackDropDown.Items = cellstr(num2str(track_nums'));
            app.CallingApp.TrackDropDown.ItemsData = track_nums';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CheckBox1 = uicheckbox(app.UIFigure);
            app.CheckBox1.Value = true;
            app.CheckBox2 = uicheckbox(app.UIFigure);
            app.CheckBox2.Text = 'Check Box2';
            app.CheckBox2.Value = true;
            app.CheckBox3 = uicheckbox(app.UIFigure);
            app.CheckBox3.Text = 'Check Box3';
            app.CheckBox3.Value = true;
            app.CheckBox4 = uicheckbox(app.UIFigure);
            app.CheckBox4.Text = 'Check Box4';
            app.CheckBox4.Value = true;
            app.CheckBox5 = uicheckbox(app.UIFigure);
            app.CheckBox5.Text = 'Check Box5';
            app.CheckBox5.Value = true;
            app.CheckBox6 = uicheckbox(app.UIFigure);
            app.CheckBox6.Text = 'Check Box6';
            app.CheckBox6.Value = true;
            app.CheckBox7 = uicheckbox(app.UIFigure);
            app.CheckBox7.Text = 'Check Box7';
            app.CheckBox7.Value = true;
            app.CheckBox8 = uicheckbox(app.UIFigure);
            app.CheckBox8.Text = 'Check Box8';
            app.CheckBox8.Value = true;
            app.CheckBox9 = uicheckbox(app.UIFigure);
            app.CheckBox9.Text = 'Check Box9';
            app.CheckBox9.Value = true;
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.ChooseTrackcategoriesLabel = uilabel(app.UIFigure);
            app.ChooseTrackcategoriesLabel.Text = 'Choose Track categories:';
            app.CheckBox10 = uicheckbox(app.UIFigure);
            app.CheckBox10.Text = 'Check Box10';
            app.CheckBox10.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChooseTracks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end