classdef TrackSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadDataMenu                 matlab.ui.container.Menu
        SaveClassificationsMenu      matlab.ui.container.Menu
        ExportDataMenu               matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        HelpButton                   matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        TrackSelectionDropDownLabel  matlab.ui.control.Label
        TrackSelectionDropDown       matlab.ui.control.DropDown
        ClassifyasDropDownLabel      matlab.ui.control.Label
        ClassifyasDropDown           matlab.ui.control.DropDown
        SelectTracksButton           matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        UnclassifiedEditFieldLabel   matlab.ui.control.Label
        UnclassifiedEditField        matlab.ui.control.NumericEditField
        EmergenceEditFieldLabel      matlab.ui.control.Label
        EmergenceEditField           matlab.ui.control.NumericEditField
        ReEntryEditFieldLabel        matlab.ui.control.Label
        ReEntryEditField             matlab.ui.control.NumericEditField
        FlybyEditFieldLabel          matlab.ui.control.Label
        FlybyEditField               matlab.ui.control.NumericEditField
        OtherEditFieldLabel          matlab.ui.control.Label
        OtherEditField               matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        start_points % Track Start Points
        end_points   % Track End Points
        h            % Handles to track plots
        h2          % handles to track starts
        dets        % detections
        opts        % Thrutracker options
        datafile    % 
        datafolder  %
        CallingApp  % ThruTracker app
    end
    
    methods (Access = private)
        
        function updateTrackCount(app)
            %Count numbers of track counts
            trackIDs = app.dets.trackID;
            app.UnclassifiedEditField.Value = sum(trackIDs==0);
            app.EmergenceEditField.Value = sum(trackIDs==1);
            app.ReEntryEditField.Value = sum(trackIDs==2);
            app.FlybyEditField.Value = sum(trackIDs==3);
            app.OtherEditField.Value = sum(trackIDs==4);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            if nargin > 1 
                app.CallingApp = main_app;
                LoadDataMenuSelected(app);
                app.datafolder = main_app.opts.projectDir;
                [path, filename] = fileparts(main_app.opts.movieFilename{1})
                app.datafile = filename;
            end
        end

        % Callback function
        function ReClassifyButtonPushed(app, event)
            % Get tracks that fulfill Track Selection Dropdown

            selected = nan(size(app.h));
            for i = 1:length(app.h)
                % See if any point was selected
                brushdata = get(app.h(i),'BrushData');
                if strcmp(app.TrackSelectionDropDown.Value, 'Any Portion')
                     selected(i) = sum(brushdata)>1;
                elseif strcmp(app.TrackSelectionDropDown.Value, 'Start')
                    selected(i) = brushdata(1);
                elseif strcmp(app.TrackSelectionDropDown.Value, 'End')
                    selected(i) = brushdata(end);
                end
            end
            idx = find(selected);
            
            % recolor selected tracks: Red emergence, Yellow re-entry, white
            % other
            colors = {'r','c','b','m'}
            % default is unclassifed (white)
            % red emergence
            % cyan = re-entry
            % blue = fly-by
            % magenta = other
            
            for i = 1:length(app.ClassifyasDropDown.Items)
                if strcmp(app.ClassifyasDropDown.Value,app.ClassifyasDropDown.Items(i))
                    value_index = i;
                    continue
                end
            end

            for i = 1:numel(idx)
                set(app.h(idx(i)),'Color',colors{value_index});
                set(app.h2(idx(i)),'Color',colors{value_index});
                app.dets.trackID(idx) = value_index;
            end
            
            %Reset brushdata
            for i = 1:numel(app.h)
                bd = get(app.h(i),'BrushData');
                set(app.h(i),'BrushData',zeros(size(bd)));
                set(app.h2(i),'BrushData',0);
            end
            updateTrackCount(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msgbox('Instructions: Click and drag a box around tracks that you want to classify. Selected portions of tracks will be highlighted in red. Use the dropdown menus at the bottom of the app to choose whether you want to select tracks that have any part selected, or only track starts or end selected, and what to classify the tracks as. Then click the "Re-Classify" button". Save the data when finished.');
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            if nargin >1            
                [app.datafile, app.datafolder] = uigetfile('Choose Data File');
                load([app.datafolder app.datafile]);
            else
                opts = app.CallingApp.opts;
                dets = app.CallingApp.dets;
            end
            
            
            hold(app.UIAxes,'on'); 
            
            try
                vid = VideoReader(opts.movieFilename{1});
            catch
                [filename pathname] = uigetfile('*.avi',[ opts.movieFilename{1}, 'not found, find location of video file'])
                vid = VideoReader([pathname filename]);
            end
            
            %vid = VideoReader(app.videoFilename)
            frame = vid.readFrame;
            set(app.UIAxes,'YDir','reverse')
            imagesc(app.UIAxes, frame);
            axis(app.UIAxes, 'image');
            
            %Find start and end points of tracks
            app.start_points = nan(max(dets.tracks2D),2);
            app.end_points = nan(max(dets.tracks2D),2);
            
            % Reset track IDs
            if ~isfield(dets,'trackID')
                 dets.trackID = zeros(max(dets.tracks2D),1);
            end
            
            %Plot tracks
            for i = 1:max(dets.tracks2D)
                idx = find(dets.tracks2D==i);
  
                trackID = dets.trackID(i);
                colors = {'w','r','c','b','m'};
                if trackID >5
                    trackID = 1;
                end
                app.h(i) = plot(app.UIAxes, dets.uvdata(idx,2),dets.uvdata(idx,3),'.-','Color',colors{trackID+1}); %Plot track

                app.h2(i) = plot(app.UIAxes, dets.uvdata(idx(1),2),dets.uvdata(idx(1),3),'o','Color',colors{trackID+1});       %Plot start
                app.start_points(i,1:2) = dets.uvdata(idx(1),2:3);
                app.end_points(i,1:2) = dets.uvdata(idx(end),2:3);
            end
            %brush(app.UIAxes,'on');
            
            %Reset Track IDs
            %dets.trackID = zeros(size(dets.trackID));
            app.dets = dets;
            app.opts = opts;
            updateTrackCount(app);
        end

        % Menu selected function: ExportDataMenu
        function ExportDataMenuSelected(app, event)
            % Make a data table
            classifications = cell(size(app.dets.trackID))
            classes = {'Unclassified','Emergence','Re-entry','Fly-by','Other'}
            for i = 1:length(classes)
                idx = find(app.dets.trackID==i-1)
                if isempty(idx)
                    continue
                else
                    classifications(idx) = {classes{i}}
                end
            end
            idx = find(diff(app.dets.tracks2D)>0);
            start_idx = [1; idx+1];
            end_idx = [idx+1; length(app.dets.uvdata)];
            startframes = app.dets.uvdata(start_idx,1);
            endframes = app.dets.uvdata(end_idx,1);
            T = table(startframes, endframes,classifications,'VariableNames', {'Start Frame', 'End Frame', 'Classification'});
            
            if exist([app.datafolder app.datafile(1:end-4) '_TrackData.csv'])
                
                answer = questdlg('File already exists, would you like to save over it?','File Exists',...
                  'Yes','No','Yes')
                if strcmp(answer,'Yes')
                    delete([app.datafolder app.datafile(1:end-4) '_TrackData.csv'])
                    writetable(T,[app.datafolder app.datafile(1:end-4) '_TrackData.csv']);
                    msgbox(['Data Saved to ' app.datafolder app.datafile(1:end-4) '_TrackData.csv'])
                end
            else
                writetable(T,[app.datafolder app.datafile(1:end-4) '_TrackData.csv']);
                msgbox(['Data Saved to ' app.datafolder app.datafile(1:end-4) '_TrackData.csv'])
            end
            
        end

        % Menu selected function: SaveClassificationsMenu
        function SaveClassificationsMenuSelected(app, event)
            dets = app.dets;
            opts = app.opts;
            save([app.datafolder app.datafile],'dets','opts');
            msgbox(['Track ID data saved to ' app.datafolder app.datafile]);
        end

        % Button down function: UIAxes
        function GetTracks(app, event)
            
        end

        % Button pushed function: SelectTracksButton
        function SelectTracksButtonPushed(app, event)
                       
            % Draw rectangle
            roi = drawrectangle(app.UIAxes);
            
            % Find tracks that are inside rectangle
            % roi.Position is bounds of rectangle
            if strcmp(app.TrackSelectionDropDown.Value, 'Any Portion')
                     xys = app.dets.uvdata(:,2:3);
                elseif strcmp(app.TrackSelectionDropDown.Value, 'Start')
                    xys =app.start_points;
                elseif strcmp(app.TrackSelectionDropDown.Value, 'End')
                    xys = app.end_points;
            end
            isIn = xys(:,1) >= roi.Position(1) & xys(:,1) <= sum(roi.Position([1,3])) ...
               & xys(:,2) >= roi.Position(2) & xys(:,2) <= sum(roi.Position([2,4])); 
          1;
            if strcmp(app.TrackSelectionDropDown.Value, 'Any Portion')
                idx = unique(app.dets.tracks2D(isIn));
            else
                idx = find(isIn);
            end
            
            colors = {'r','c','b','m'}
            % default is unclassifed (white)
            % red emergence
            % cyan = re-entry
            % blue = fly-by
            % magenta = other
            
            % Check to see what to classify tracks as
            for i = 1:length(app.ClassifyasDropDown.Items)
                if strcmp(app.ClassifyasDropDown.Value,app.ClassifyasDropDown.Items(i))
                    value_index = i;
                    continue
                end
            end

            % Classify Tracks
            for i = 1:numel(idx)
                set(app.h(idx(i)),'Color',colors{value_index});
                set(app.h2(idx(i)),'Color',colors{value_index});
                app.dets.trackID(idx) = value_index;
            end
            
            % Clear rectangles
            delete(findall(app.UIAxes, 'Type',  'images.roi.Rectangle'))
            updateTrackCount(app);
            1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.SaveClassificationsMenu = uimenu(app.FileMenu);
            app.SaveClassificationsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveClassificationsMenuSelected, true);
            app.SaveClassificationsMenu.Text = 'Save Classifications';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataMenuSelected, true);
            app.ExportDataMenu.Text = 'Export Data';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help!';
            app.Panel = uipanel(app.UIFigure);
            app.TrackSelectionDropDownLabel = uilabel(app.Panel);
            app.TrackSelectionDropDownLabel.Text = 'Track Selection';
            app.TrackSelectionDropDown = uidropdown(app.Panel);
            app.TrackSelectionDropDown.Items = {'Any Portion', 'Start', 'End'};
            app.TrackSelectionDropDown.Value = 'Any Portion';
            app.ClassifyasDropDownLabel = uilabel(app.Panel);
            app.ClassifyasDropDownLabel.Text = 'Classify as';
            app.ClassifyasDropDown = uidropdown(app.Panel);
            app.ClassifyasDropDown.Items = {'Emergence', 'Re-Entry', 'Fly-by', 'Other'};
            app.ClassifyasDropDown.Value = 'Emergence';
            app.SelectTracksButton = uibutton(app.Panel, 'push');
            app.SelectTracksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTracksButtonPushed, true);
            app.SelectTracksButton.Text = 'Select Tracks';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UnclassifiedEditFieldLabel = uilabel(app.Panel_2);
            app.UnclassifiedEditFieldLabel.Text = 'Unclassified';
            app.UnclassifiedEditField = uieditfield(app.Panel_2, 'numeric');
            app.UnclassifiedEditField.Editable = 'off';
            app.EmergenceEditFieldLabel = uilabel(app.Panel_2);
            app.EmergenceEditFieldLabel.Text = 'Emergence';
            app.EmergenceEditField = uieditfield(app.Panel_2, 'numeric');
            app.EmergenceEditField.Editable = 'off';
            app.ReEntryEditFieldLabel = uilabel(app.Panel_2);
            app.ReEntryEditFieldLabel.Text = 'Re-Entry';
            app.ReEntryEditField = uieditfield(app.Panel_2, 'numeric');
            app.ReEntryEditField.Editable = 'off';
            app.FlybyEditFieldLabel = uilabel(app.Panel_2);
            app.FlybyEditFieldLabel.Text = 'Fly-by';
            app.FlybyEditField = uieditfield(app.Panel_2, 'numeric');
            app.FlybyEditField.Editable = 'off';
            app.OtherEditFieldLabel = uilabel(app.Panel_2);
            app.OtherEditFieldLabel.Text = 'Other';
            app.OtherEditField = uieditfield(app.Panel_2, 'numeric');
            app.OtherEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @GetTracks, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end