classdef TurbineAdvancedSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdvSettingsFig               matlab.ui.Figure
        WindTurbineAdvancedSettingsLabel  matlab.ui.control.Label
        TurbineSelectionButtonGroup  matlab.ui.container.ButtonGroup
        MHIVestasV16495MWButton      matlab.ui.control.RadioButton
        VestasV9018MWButton          matlab.ui.control.RadioButton
        GE15MWButton                 matlab.ui.control.RadioButton
        StandardButton               matlab.ui.control.RadioButton
        CustomButton                 matlab.ui.control.RadioButton
        CustomSettingsPanel          matlab.ui.container.Panel
        HubHeightLabel               matlab.ui.control.Label
        HubHeightEditField           matlab.ui.control.NumericEditField
        BladeLengthLabel             matlab.ui.control.Label
        BladeLengthEditField         matlab.ui.control.NumericEditField
        UpdateTurbineSizeButton      matlab.ui.control.Button
        AnimationPanel               matlab.ui.container.Panel
        AngleEditFieldLabel          matlab.ui.control.Label
        AngleEditField               matlab.ui.control.NumericEditField
        SpeedEditFieldLabel          matlab.ui.control.Label
        SpeedEditField               matlab.ui.control.NumericEditField
        PlayAnimationButton          matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        BatVisApp % Parent App
        vrTrans % new turbine models' vrTrans
        vis % Bat Track figure
        fighandle % handle to app.vis from parent app
        normal = 'black'; % color of normal messages
        warning = 'blue'; % color of warning messages
        error = 'red'; % color of error messages
        %         stopAnimation % flag to stop animation
        %         lastTurn % last value of i when stopping the animation
        %         play
    end
    
    methods (Access = private)
        
        function turbine = createTurbineModel(app,hub_height,scale)
            
            %% This function alters an original turbine model by a scaler amount.
            %
            % Inputs: hub_height = the height of the turbine in meters
            %         blade_length = the diameter of the turbines' blades
            %
            % Outputs: model = the turbine model altered with the new values
            %
            % Authors: William Valentine & Aaron Corcoran
            turbine = load('turbineModel_v3.mat');
            scale = scale / 45;
            origVR = turbine.obj.vR_trans;
            foo = turbine.obj.vR_trans;
            % Get the value index of the hub height
            hub_height_idx = find(foo(:,3) < -90);
            % Set the new value of the hub height
            foo(hub_height_idx,3) = -hub_height;
            % Find the index of the blades
            doo = rnorm(foo(:,2:3));
            % Get the blade length index
            blade_length_idx = find(doo > 40 & doo < 47);
            % Scale the old blade length by the 'scale' value
            foo(blade_length_idx,2:3) = foo(blade_length_idx,2:3).*scale;
            turbine.obj.vR_trans = foo;
        end
        
    end
    
    methods (Access = public)
        
        function updateVis(app,dispTracks,summ)
            resetVis(app.BatVisApp);
            msg = sprintf('Value of hub height: %.1f\tValue of Blade Length: %.1f',app.HubHeightEditField.Value,app.BladeLengthEditField.Value);
            status(app.BatVisApp,msg,app.normal);
            tb = createTurbineModel(app,app.HubHeightEditField.Value,app.BladeLengthEditField.Value);
            turbineVrTrans(app.BatVisApp,tb.obj.vR_trans);
            makeVis_v3(app.BatVisApp,dispTracks,summ,app.fighandle,tb.obj.vR_trans);
        end
        
        function animation(app,play,stop,restart)            % Get Axes handle
            figure(app.fighandle);
            ax = gca(app.fighandle);
            speed = app.SpeedEditField.Value / 1000;
            angle = app.AngleEditField.Value;
            start = -90;
            maxTurn = 25000;
            status(app.BatVisApp,'',app.normal);
            %             app.stopAnimation = 0;
            
            if stop
                ax.View = [lastTurn angle];
                %                 return;
            elseif play % turn starting from last value of i
                for i=lastTurn:maxTurn
                    pause(speed);
                    ax.View = [i angle]; % rotate right
                    msg = sprintf('Turn: %d, Last Turn: %d',i,lastTurn);
                    status(app.BatVisApp,msg,'black');
                    lastTurn = i;
                end
            elseif restart
                ax.View = [start angle];
                lastTurn = 0;
                for i=start:maxTurn
                    pause(speed);
                    ax.View = [i angle]; % rotate right
                    msg = sprintf('Turn: %d, Last Turn: %d',i,lastTurn);
                    status(app.BatVisApp,msg,'black');
                    lastTurn = i;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, fighandle)
            app.BatVisApp = mainapp;
            app.fighandle = fighandle;
            
            % move
            mainAppPosition = app.BatVisApp.BatVisFig.Position;
            app.AdvSettingsFig.Position(1:2)=mainAppPosition(1:2)+[883,0];
        end

        % Button pushed function: UpdateTurbineSizeButton
        function UpdateTurbineSizeButtonPushed(app, event)
            resetVis(app.BatVisApp);
            msg = sprintf('Value of hub height: %.1f\tValue of Blade Length: %.1f',app.HubHeightEditField.Value,app.BladeLengthEditField.Value);
            status(app.BatVisApp,msg,app.normal);
            tb = createTurbineModel(app,app.HubHeightEditField.Value,app.BladeLengthEditField.Value);
            turbineVrTrans(app.BatVisApp,tb.obj.vR_trans);
            makeVis_v3(app.BatVisApp,[],[],app.fighandle,tb.obj.vR_trans);
        end

        % Selection changed function: TurbineSelectionButtonGroup
        function TurbineSelectionButtonGroupSelectionChanged(app, event)
            % Types of turbines:
            % MHI Vestas V164-9.5 MW: 262ft blades on a 345ft tower
            % Vestas V90 1.8 MW: 148ft blades on a 262ft tower
            % GE 1.5 MW: 116ft blades on a 212ft tower
            % Standard: 45ft blades on a 100ft tower
            % Custom: bl ft blades on a hh ft tower
            selectedButton = app.TurbineSelectionButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'MHI Vestas V164-9.5 MW')
                app.HubHeightEditField.Value = 345.0;
                app.BladeLengthEditField.Value = 262.0;
            elseif strcmp(selectedButton.Text,'Vestas V90 1.8 MW')
                app.HubHeightEditField.Value = 262.0;
                app.BladeLengthEditField.Value = 148.0;
            elseif strcmp(selectedButton.Text,'GE 1.5 MW')
                app.HubHeightEditField.Value = 212.0;
                app.BladeLengthEditField.Value = 116.0;
            elseif strcmp(selectedButton.Text,'Standard')
                app.HubHeightEditField.Value = 100.0;
                app.BladeLengthEditField.Value = 45.0;
            else
                app.HubHeightEditField.Value = app.HubHeightEditField.Value;
                app.BladeLengthEditField.Value = app.BladeLengthEditField.Value;
            end
        end

        % Callback function
        function PlayButtonPushed(app, event)
            animation(app,1,0,0); % play
        end

        % Callback function
        function StopButtonPushed(app, event)
            animation(app,0,1,0); % stop
        end

        % Value changed function: PlayAnimationButton
        function PlayAnimationButtonValueChanged(app, event)
            value = app.PlayAnimationButton.Value;
            
            if value == 0
                app.PlayAnimationButton.Text = 'Play Animation';
                return
            else
                app.PlayAnimationButton.Text = 'Stop Animation';
            end
            figure(app.fighandle);
            ax = gca(app.fighandle);
            
            i = ax.View(1);
            while value
                ax.View = [i app.AngleEditField.Value]; % rotate right
                lastTurn = i;
                msg = sprintf('Turn: %d, Last Turn: %d',i,lastTurn);
                status(app.BatVisApp,msg,'blue');
                i = i + 1;
                drawnow
                pause(0.5/app.SpeedEditField.Value);
                value = app.PlayAnimationButton.Value;
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdvSettingsFig = uifigure('Visible', 'off');
            app.AdvSettingsFig.Name = 'Advanced Settings';
            app.WindTurbineAdvancedSettingsLabel = uilabel(app.AdvSettingsFig);
            app.WindTurbineAdvancedSettingsLabel.Text = {'Wind Turbine '; 'Advanced Settings'};
            app.TurbineSelectionButtonGroup = uibuttongroup(app.AdvSettingsFig);
            app.TurbineSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TurbineSelectionButtonGroupSelectionChanged, true);
            app.TurbineSelectionButtonGroup.Title = 'Turbine Selection';
            app.MHIVestasV16495MWButton = uiradiobutton(app.TurbineSelectionButtonGroup);
            app.MHIVestasV16495MWButton.Text = 'MHI Vestas V164-9.5 MW';
            app.VestasV9018MWButton = uiradiobutton(app.TurbineSelectionButtonGroup);
            app.VestasV9018MWButton.Text = 'Vestas V90 1.8 MW';
            app.GE15MWButton = uiradiobutton(app.TurbineSelectionButtonGroup);
            app.GE15MWButton.Text = 'GE 1.5 MW';
            app.StandardButton = uiradiobutton(app.TurbineSelectionButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.CustomButton = uiradiobutton(app.TurbineSelectionButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.CustomSettingsPanel = uipanel(app.AdvSettingsFig);
            app.CustomSettingsPanel.Title = 'Custom Settings';
            app.HubHeightLabel = uilabel(app.CustomSettingsPanel);
            app.HubHeightLabel.Text = 'Hub Height:';
            app.HubHeightEditField = uieditfield(app.CustomSettingsPanel, 'numeric');
            app.HubHeightEditField.Value = 100;
            app.BladeLengthLabel = uilabel(app.CustomSettingsPanel);
            app.BladeLengthLabel.Text = 'Blade Length:';
            app.BladeLengthEditField = uieditfield(app.CustomSettingsPanel, 'numeric');
            app.BladeLengthEditField.Value = 45;
            app.UpdateTurbineSizeButton = uibutton(app.CustomSettingsPanel, 'push');
            app.UpdateTurbineSizeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTurbineSizeButtonPushed, true);
            app.UpdateTurbineSizeButton.Text = 'Update Turbine Size';
            app.AnimationPanel = uipanel(app.AdvSettingsFig);
            app.AnimationPanel.Title = 'Animation';
            app.AngleEditFieldLabel = uilabel(app.AnimationPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.AngleEditField.Tooltip = {'2D = 0'; '3D = 30'; 'Top = 90'; 'Bottom = -90'};
            app.SpeedEditFieldLabel = uilabel(app.AnimationPanel);
            app.SpeedEditFieldLabel.Text = 'Speed';
            app.SpeedEditField = uieditfield(app.AnimationPanel, 'numeric');
            app.SpeedEditField.Tooltip = {'Slow = 10'; 'Medium = 20'; 'Fast = 30'};
            app.SpeedEditField.Value = 10;
            app.PlayAnimationButton = uibutton(app.AnimationPanel, 'state');
            app.PlayAnimationButton.ValueChangedFcn = createCallbackFcn(app, @PlayAnimationButtonValueChanged, true);
            app.PlayAnimationButton.Text = 'Play Animation';
            % Show the figure after all components are created
            app.AdvSettingsFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TurbineAdvancedSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdvSettingsFig)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdvSettingsFig)
        end
    end
end