classdef batVis_gui_v3_2019b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatVisFig                     matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadexampleMenu               matlab.ui.container.Menu
        LoadDataMenu                  matlab.ui.container.Menu
        ExportDataMenu                matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        QuitMenu                      matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        MoreoptionsMenu               matlab.ui.container.Menu
        ShowAdvancedSettingsMenu      matlab.ui.container.Menu
        ShowBatTracksMenu             matlab.ui.container.Menu
        CloseSaveLogMenu              matlab.ui.container.Menu
        BatTrackAnimationMenu         matlab.ui.container.Menu
        ChangeBackgroundMenu          matlab.ui.container.Menu
        WhiteMenu                     matlab.ui.container.Menu
        LightGrayMenu                 matlab.ui.container.Menu
        LightBlueMenu                 matlab.ui.container.Menu
        DarkBlueMenu                  matlab.ui.container.Menu
        OliveMenu                     matlab.ui.container.Menu
        AmberMenu                     matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        KeyboardshortcutsMenu         matlab.ui.container.Menu
        VersionMenu                   matlab.ui.container.Menu
        TrackSelector                 matlab.ui.container.Panel
        DayDropDownLabel              matlab.ui.control.Label
        DayDropDown                   matlab.ui.control.DropDown
        SiteDropDownLabel             matlab.ui.control.Label
        SiteDropDown                  matlab.ui.control.DropDown
        SetDropDownLabel              matlab.ui.control.Label
        SetDropDown                   matlab.ui.control.DropDown
        TreatmentDropDownLabel        matlab.ui.control.Label
        TreatmentDropDown             matlab.ui.control.DropDown
        TrackCheckBox                 matlab.ui.control.Table
        TracksLabel                   matlab.ui.control.Label
        InterferenceKnobLabel         matlab.ui.control.Label
        InterferenceKnob              matlab.ui.control.DiscreteKnob
        AllTracksCheckBox             matlab.ui.control.CheckBox
        TrackSummaryDataPanel         matlab.ui.container.Panel
        TrackLengthmLabel             matlab.ui.control.Label
        TrackLength                   matlab.ui.control.NumericEditField
        MedGroundSpeedmsLabel         matlab.ui.control.Label
        GroundSpeed                   matlab.ui.control.NumericEditField
        AvgAltitudeaboveHubmLabel     matlab.ui.control.Label
        Altitude                      matlab.ui.control.NumericEditField
        MinDistancetoHubmLabel        matlab.ui.control.Label
        BladeDist                     matlab.ui.control.NumericEditField
        MaxCentripetalForcems2Label   matlab.ui.control.Label
        MaxCentForce                  matlab.ui.control.NumericEditField
        MedCentripetalForcems2Label   matlab.ui.control.Label
        MeanCentForce                 matlab.ui.control.NumericEditField
        SinuosityradLabel             matlab.ui.control.Label
        Sinuosity                     matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        FileViewer                    matlab.ui.control.TextArea
        BatTurbineVisualizationLabel  matlab.ui.control.Label
        StatusLabel                   matlab.ui.control.Label
        VersionLabel                  matlab.ui.control.Label
    end

    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % v2.1 bat visualization GUI - imports all bat tracks, smooths and interpolates them.
    % Imports the corresponding turbine pts and rotates and translates such that the hub is
    % origin and the nacelle is along the positive X axis. All bat tracks are rotated and
    % traslated to match the orientation of the hub-nacelle vector.
    % The previous versions of the GUI had separate callback functions for each of the drop
    % down menus and the track summary data. In the present GUI, getTrack takes care of all the
    % user inputs and updateTrackSumm updates the track metrics
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    properties (Access = private)
        batCell % master bat cell data
        tmp_batCell % batCell chosen by user
        tmp_batInt % interference values for batCell chosen by user
        int_batCell % batCell in accordance with the interference value chosen by user
        chkbox_batCell % batCell in accordance with checkboxes selected by user
        summ % selected tracks summary
        logFile_makeBatCell % log of all operations performed
        prevState_allTracksCheckBox % previous state of all tracks selector checkbox
        export_batCell % file to export
        saveLogFig % figure of the save file window
        show_logFile_makeBatCell % here so that the save button works
        trackList % get tracks from track list
        path % main folder path for app
        turbineSettingsApp % handle for turbine settings app
        normal = 'black'; % set the color for normal messages
        warning = 'blue'; % set the color for warnings
        error = 'red'; % set the color for errors that (may) break the program
        majorP = 1; % Version number: 1.0.0
        minorP = 0;
        miniP = 0;
        backgroundOpt;
        vrTrans % turbine model vR_trans from turbine advanced settings app
        stopAnimation % flag from quit menu to stop animation of the bat tracks before closing the app
    end
    
    properties (Access = public)
        vis % 3D plot
    end
    
    methods (Access = private)
        
        function chckBatFig(app)
            % check existence of figure
            chckFig=findobj(app.vis);
            if isempty(chckFig)
                app.vis=figure('Name','Bat Tracks v3','Color','white');
                set(app.vis,'NumberTitle','off','Tag','v3');
                status(app,'New figure created since the previous figure was closed',app.normal);
                mainAppPosition = app.BatVisFig.Position;
                app.vis.Position = mainAppPosition+[1147,0,0,-32];
                %                 app.vis.Position(1:2) = mainAppPosition(1:2)+[1147,90];
            end
        end
        
        function ChangeBackgroundColor(app,color)
            % change background colors in main app
            app.BatVisFig.Color = color;
            app.TrackSelector.BackgroundColor = color;
            app.TrackSummaryDataPanel.BackgroundColor = color;
            app.StatusLabel.BackgroundColor = color;
            app.DayDropDownLabel.BackgroundColor = color;
            app.SiteDropDownLabel.BackgroundColor = color;
            app.SetDropDownLabel.BackgroundColor = color;
            app.TreatmentDropDownLabel.BackgroundColor = color;
            app.VersionLabel.BackgroundColor = color;
            app.FileViewer.BackgroundColor = color;
            
            % change background colors in advanced settings app
            app.turbineSettingsApp.AdvSettingsFig.Color = color;
            app.turbineSettingsApp.TurbineSelectionButtonGroup.BackgroundColor = color;
            app.turbineSettingsApp.CustomSettingsPanel.BackgroundColor = color;
            app.turbineSettingsApp.UpdateButton.BackgroundColor = color;
            
            % change background color for Bat Tracks Vis
            app.vis.Color = color;
        end
    end
    
    methods (Access = public)
        
        function updateTrackSumm(app)
            if ~isempty(app.summ)
                app.TrackLength.Value=nanmean([app.summ.trackLen]);
                app.GroundSpeed.Value=nanmean([app.summ.gs_med]);
                app.MeanCentForce.Value=nanmean([app.summ.cf_med]);
                app.MaxCentForce.Value=nanmean([app.summ.cf_max]);
                app.Altitude.Value=nanmean([app.summ.alt_avg]);
                app.BladeDist.Value=nanmean([app.summ.dist2hub_min]);
                
                %                 app.FlightSpeed.Value=nanmean([app.summ.flight_speed]);
                app.Sinuosity.Value=nanmean([app.summ.sinuosity]);
                %                 app.ScaledPixelSize.Value=nanmean([app.summ.scaled_pixel]);
                %                 app.FlightHeightAboveGround.Value=nanmean([app.summ.height_above_ground]);
                %                 app.FlightPositionRelativetoTurbinesmLabel.Value=nanmean([app.summ.pos_relative_turbines]);
            else
                app.TrackLength.Value=0;
                app.GroundSpeed.Value=0;
                app.MeanCentForce.Value=0;
                app.MaxCentForce.Value=0;
                app.Altitude.Value=0;
                app.BladeDist.Value=0;
                
                %                 app.FlightSpeed.Value=0;
                %                 app.Sinuosity.Value=0;
                %                 app.ScaledPixelSize.Value=0;
                %                 app.FlightHeightAboveGround.Value=0;
                %                 app.FlightPositionRelativetoTurbinesmLabel.Value=0;
            end
        end
        
        function resetVis(app)
            try
                ax = gca(app.vis);
                cla(ax);
            catch
                status(app,'ERROR: Cannot find axes',app.error);
            end
        end
        
        function makeVis_v3(app,dispTrack,sum,figHandle,vrTrans)
            
            % Load the 3d turbine model
            turbine=load('turbineModel_v3');
            %             delete(findobj(figHandle,'Type','line'));
            figHandle=figure(app.vis);
            mainAppPosition = app.BatVisFig.Position;
            app.vis.Position = mainAppPosition+[1147,0,0,-32];
            
            % Set axes properties
            hold on;
            axis equal;
            grid on;
            xlabel('X (m)');ylabel('Y (m)');zlabel('Z (m)');
            view(3);
            
            %             % Get tracks
            %             getTracks(app);
            %             % Update summ
            %             updateTrackSumm(app);
            
            % Get dispTracks and summ
            if isempty(dispTrack) && isempty(sum) % update the turbine settings
                dispTracks = app.int_batCell;
                summ = app.summ;
            else % for every other command
                dispTracks = dispTrack;
                summ = sum;
            end
            
            % Get old/new turbine models' vrTrans
            if ~isempty(vrTrans)
                % plot the turbine using 'patch'
                VisT=patch('Faces',turbine.obj.f.v,'Vertices',vrTrans);
            else
                % plot the turbine using 'patch'
                VisT=patch('Faces',turbine.obj.f.v,'Vertices',turbine.obj.vR_trans);
            end
            
            VisT.FaceColor=[.45 .55 .6];
            VisT.FaceAlpha=.25;
            
            if ~isempty(dispTracks)
                % Plot the selected bat tracks
                pts_cell=dispTracks(:,11); % make cell array of all the tracks
                color_cell=dispTracks(:,13); % get cell array of track colors
                trackName_cell=dispTracks(:,7); % get track names
                % plot tracks in a 3D
                for i=1:numel(pts_cell)
                    pts=pts_cell{i};
                    color=color_cell{i};
                    trackName=trackName_cell{i};
                    try
                        trackSumm=summ(i);
                    catch
                        status(app,'Index exceeds the number of array elements (0)',app.error);
                        trackSumm=0;
                    end
                    h(i)=plot3(pts(:,1),pts(:,2),pts(:,3),'LineWidth',1,'Color',color,'Marker','o',...
                        'MarkerSize',3,'MarkerFaceColor','r','MarkerEdgeColor','r','MarkerIndices',1,...
                        'Tag',trackName,'DisplayName',sprintf('Track id selected - \n%s',trackName),'UserData',trackSumm);
                end
                
                % make figure interactive
                set(h,'ButtonDownFcn',@makeVis_interactive_v1);
                figHandle.Pointer='crosshair'; % so that the user knows when track selection is active
            end
            hold off
            axis equal
            % make figure title showing #tracks
            if isempty(dispTracks)
                nTracks=0;
            else nTracks=size(dispTracks,1);
            end
            
            %Open Advanced Settings
            try
                turbine = app.turbineSettingsApp.TurbineSelectionButtonGroup.SelectedObject.Text;
            catch
                ShowAdvancedSettingsMenuSelected(app);
                turbine = app.turbineSettingsApp.TurbineSelectionButtonGroup.SelectedObject.Text;
            end
            
            batTrackTitle = sprintf('Number of bat tracks = %d \nTurbine: % s',nTracks,turbine);
            title(batTrackTitle);
            legend('off');
            
            %% figure interactivity
            function makeVis_interactive_v1(varargin)
                
                % get the object that produced the call to this function
                [trackProp,~]=gcbo(); % ~ is for not getting the figure handle
                if trackProp.LineWidth==1
                    set(trackProp,'LineWidth',1.5);
                    l=legend(trackProp);
                    l.Interpreter='none';
                    l.Location='eastoutside'; % best
                    l.Box='off';
                    %set(l,'Location','northeastoutside'); % to show the underscore not as a subscript
                    % set previous line back to linewidth of 1
                    trackProp_idx=cellfun(@(x) strcmpi(trackProp.Tag,x),{h.Tag});
                    otherTracks_idx=find(trackProp_idx==0);
                    set(h(otherTracks_idx),'LineWidth',1);
                    
                    % display selected track information
                    trackInfo=trackProp.UserData;
                    info=sprintf('Track metrics:');
                    info=sprintf('%s\n=======================',info);
                    info=sprintf('%s\n\tTrack frames = %s',info,num2str(trackInfo.trackFrames));
                    info=sprintf('%s\n\tTrack Time = %.2f s',info,trackInfo.trackTime);
                    info=sprintf('%s\n\tTrack Length = %.2f m',info,trackInfo.trackLen);
                    info=sprintf('%s\n\tMed. Ground Speed = %.2f m/s',info,trackInfo.gs_med);
                    info=sprintf('%s\n\tMin. Ground Speed = %.2f m/s',info,trackInfo.gs_min);
                    info=sprintf('%s\n\tMax. Ground Speed = %.2f m/s',info,trackInfo.gs_max);
                    info=sprintf('%s\n\tMed. Centripetal Force = %.2f m/s2',info,trackInfo.cf_med);
                    info=sprintf('%s\n\tMax. Centripetal Force = %.2f m/s2',info,trackInfo.cf_max);
                    info=sprintf('%s\n\tAvg. Altitude above Hub = %.2f m',info,trackInfo.alt_avg);
                    info=sprintf('%s\n\tMax. Altitude above Hub = %.2f m',info,trackInfo.alt_max);
                    info=sprintf('%s\n\tMin. Distance to Blade plane = %.2f m',info,trackInfo.minBD);
                    info=sprintf('%s\n\tMin. Distance to Hub = %.2f m',info,trackInfo.dist2hub_min);
                    info=sprintf('%s\n\tSinuosity = %.2f',info,trackInfo.sinuosity);
                    if trackInfo.intBlade==0
                        int='No';
                    else int='Yes';
                    end
                    info=sprintf('%s\n\tBlade Interference = %s',info,int);
                    % show track info in legend
                    hold on
                    plot_spacer=plot3(nan,nan,nan);
                    plot_spacer.Color='w';
                    plot_spacer.DisplayName='';
                    plot_info=plot3(nan,nan,nan);
                    plot_info.Color='w';
                    plot_info.DisplayName=info;
                    hold off
                else % user clicked line again, so revert back to original line width
                    set(trackProp,'LineWidth',1);
                    legend('off');
                end
            end
        end
        
        function getTracks(app)
            usr_day = app.DayDropDown.Value;
            usr_site=app.SiteDropDown.Value;
            usr_set=app.SetDropDown.Value;
            usr_trt=app.TreatmentDropDown.Value;
            %             fltspd=app.flight_speed;
            %             sin=app.sinuosity;
            %             sps=app.scaled_pixel;
            %             fhag=app.height_above_ground;
            %             fprt=app.pos_relative_turbines;
            % get user selected tracks
            [app.tmp_batCell,app.tmp_batInt,app.DayDropDown.Items,app.SiteDropDown.Items,app.SetDropDown.Items,app.TreatmentDropDown.Items]...
                =batCell_lookup_v2(usr_day,usr_site,usr_set,usr_trt,app.batCell);
            % check for interference
            track_int=app.tmp_batInt;
            int_yes_idx=find(track_int==1);
            int_no_idx=find(track_int==0);
            % check user chosen state of interference
            usr_int_value=app.InterferenceKnob.Value;
            % populate the track list based on user selected interference value
            switch usr_int_value % set batCell that is used to populate the track list
                case 'Off'
                    app.int_batCell=app.tmp_batCell; % update the batCell being fed to trackCheckBox
                    int_trackList=app.tmp_batCell(:,7); % update the trackList being displayed in the GUI
                    app.AllTracksCheckBox.Value=true;
                    app.prevState_allTracksCheckBox=true;
                case 'Yes'
                    app.int_batCell=app.tmp_batCell(int_yes_idx,:);
                    int_trackList=app.tmp_batCell(int_yes_idx,7);
                    app.AllTracksCheckBox.Value=true;
                    app.prevState_allTracksCheckBox=true;
                case 'No'
                    app.int_batCell=app.tmp_batCell(int_no_idx,:);
                    int_trackList=app.tmp_batCell(int_no_idx,7);
                    app.AllTracksCheckBox.Value=true;
                    app.prevState_allTracksCheckBox=true;
            end
            
            %             % Get track list and send to other callback
            %             app.trackList = int_trackList;
            % calculate track summary data
            app.summ=trackSummary_v1(app.int_batCell);
            % populate track list
            app.TrackCheckBox.Data=[int_trackList repmat({true},numel(int_trackList),1)];
            % update track summary data
            updateTrackSumm(app);
            % check for figure existence
            chckBatFig(app);
            % plot figure - turbine and bat tracks
            makeVis_v3(app,app.int_batCell,app.summ,app.vis,[]);
            % get file to export if the user hits export right after changing interference or day, site, set or treatment options
            app.export_batCell=app.int_batCell;
        end
        
        function status(app,message,color)
            app.StatusLabel.Visible = 'on';
            
            if nargin <=2
                color = 'black';
            end
            set(app.StatusLabel,'Text',message);
            set(app.StatusLabel,'FontColor',color);
        end
        
        function version(app)
            app.VersionLabel.Visible = 'on';
            msg = sprintf('%d.%d.%d',app.majorP,app.minorP,app.miniP);
            set(app.VersionLabel,'Text',msg,'FontColor','black');
        end
        
        function turbineVrTrans(app,tb)
            app.vrTrans = [];
            app.vrTrans = tb;
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set location
            try
                desktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                desktopMainFrame = desktop.getMainFrame;
                b=desktopMainFrame.getBounds;
                % move
                mainAppPosition = app.BatVisFig.Position;
                app.BatVisFig.Position(1:2)=[b.x,b.y]+[10,100];
            catch
                % nothing
            end
            
            % disable all buttons except initialization in GUI
            app.ExportDataMenu.Enable='off';
            app.SaveMenu.Enable='off';
            app.QuitMenu.Enable='on';
            app.FileViewer.Visible='off';
            app.VersionLabel.Text='';
            app.StatusLabel.Text='';
            app.DayDropDown.Enable='off'; app.DayDropDown.Items={'Choose Day'};
            app.SiteDropDown.Enable='off'; app.SiteDropDown.Items={'Choose Site'};
            app.SetDropDown.Enable='off'; app.SetDropDown.Items={'Choose Set'};
            app.TreatmentDropDown.Enable='off'; app.TreatmentDropDown.Items={'Choose Treatment'};
            app.TrackCheckBox.Enable='off';
            app.TrackLength.Enable='off';
            app.GroundSpeed.Enable='off';
            app.Altitude.Enable='off';
            app.MaxCentForce.Enable='off';
            app.MeanCentForce.Enable='off';
            app.BladeDist.Enable='off';
            app.StatusLabel.Text = '';
            
            % Track summary
            %app.FlightSpeed.Enable='off'; % Flight Speed
            app.Sinuosity.Enable='off'; % Sinuosity
            %app.ScaledPixelSize.Enable='off'; % Scaled pixel size
            %app.FlightHeightAboveGround.Enable='off'; % Flight height above ground
            %app.FlightPositionRelativeTurbines.Enable='off'; % Flight position relative to turbines
            
            app.InterferenceKnob.Enable='off';
            app.InterferenceKnob.Value='Off';
            app.AllTracksCheckBox.Enable='off';
            
            file = 'B:\Projects\WindTurbines\BatVis3\Data\shortcuts.txt';
            %str = extractFileText(file);   %Not needed. From a toolbox
            fileID = fopen(file);
            str = fscanf(fileID, '%s');
            fclose(fileID);
            x = strfind(str, "start");
            y = strfind(str, "end");
            start = x(1);
            fin = y(1);
            file = extractBetween(str, start+6, fin-3);
            app.FileViewer.Value = file;
            app.FileViewer.FontSize = 20;
            app.FileViewer.FontName = 'Times New Roman';
            
            % display GUI information on screen
            fprintf('\n');
            disp('Bat Turbine visualization GUI v3');
            disp('Read documentation for more details');
            fprintf('\n');
            disp('July, 2018 - Jonathan Rader');
            disp('March, 2020 - Aaron Corcoran & William Valentine');
            
            % get main path
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                app.path = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                app.path = pwd;
            end
            
            figure(app.BatVisFig);
        end

        % Menu selected function: WhiteMenu
        function WhiteMenuSelected(app, event)
            app.StatusLabel.Text = '';
            app.WhiteMenu.Checked = 'on';
            app.LightGrayMenu.Checked = 'off';
            app.DarkBlueMenu.Checked = 'off';
            app.LightBlueMenu.Checked = 'off';
            app.OliveMenu.Checked = 'off';
            app.AmberMenu.Checked = 'off';
            ChangeBackgroundColor(app,'white');
            status(app,'Background color: White',app.normal);
        end

        % Menu selected function: LightGrayMenu
        function LightGrayMenuSelected(app, event)
            app.StatusLabel.Text = '';
            app.WhiteMenu.Checked = 'off';
            app.LightGrayMenu.Checked = 'on';
            app.DarkBlueMenu.Checked = 'off';
            app.LightBlueMenu.Checked = 'off';
            app.OliveMenu.Checked = 'off';
            app.AmberMenu.Checked = 'off';
            ChangeBackgroundColor(app,[0.70,0.70,0.70]); % darkish gray
            status(app,'Background color: Light Gray',app.normal);
        end

        % Menu selected function: LightBlueMenu
        function LightBlueMenuSelected(app, event)
            app.StatusLabel.Text = '';
            app.WhiteMenu.Checked = 'off';
            app.LightGrayMenu.Checked = 'off';
            app.DarkBlueMenu.Checked = 'off';
            app.LightBlueMenu.Checked = 'on';
            app.OliveMenu.Checked = 'off';
            app.AmberMenu.Checked = 'off';
            ChangeBackgroundColor(app,'#4DBEEE');
            status(app,'Background color: Light Blue',app.normal);
        end

        % Menu selected function: DarkBlueMenu
        function DarkBlueMenuSelected(app, event)
            app.StatusLabel.Text = '';
            app.WhiteMenu.Checked = 'off';
            app.LightGrayMenu.Checked = 'off';
            app.DarkBlueMenu.Checked = 'on';
            app.LightBlueMenu.Checked = 'off';
            app.OliveMenu.Checked = 'off';
            app.AmberMenu.Checked = 'off';
            ChangeBackgroundColor(app,'#0072BD');
            status(app,'Background color: Dark Blue',app.normal);
        end

        % Menu selected function: OliveMenu
        function OliveMenuSelected(app, event)
            app.StatusLabel.Text = '';
            app.WhiteMenu.Checked = 'off';
            app.LightGrayMenu.Checked = 'off';
            app.DarkBlueMenu.Checked = 'off';
            app.LightBlueMenu.Checked = 'off';
            app.OliveMenu.Checked = 'on';
            app.AmberMenu.Checked = 'off';
            ChangeBackgroundColor(app,'#77AC30');
            status(app,'Background color: Olive',app.normal);
        end

        % Menu selected function: AmberMenu
        function AmberMenuSelected(app, event)
            app.StatusLabel.Text = '';
            app.WhiteMenu.Checked = 'off';
            app.LightGrayMenu.Checked = 'off';
            app.DarkBlueMenu.Checked = 'off';
            app.LightBlueMenu.Checked = 'off';
            app.OliveMenu.Checked = 'off';
            app.AmberMenu.Checked = 'on';
            ChangeBackgroundColor(app,'#EDB120');
            status(app,'Background color: Amber',app.normal);
        end

        % Callback function: AllTracksCheckBox, TrackCheckBox
        function TrackCheckBoxCellSelection(app, event)
            app.StatusLabel.Text = '';
            % option selected by user
            chkbox_value=cell2mat(app.TrackCheckBox.Data(:,2)); % get all values of checkbox as array
            nTracks=numel(chkbox_value); % get total number of tracks
            usr_choice=find(chkbox_value==1); % get idx of tracks chosen by user
            nChosen=numel(usr_choice); % #tracks chosen by user
            % get value of allTracks selection
            allTracks=app.AllTracksCheckBox.Value;
            
            if nChosen==0 & allTracks==true
                app.TrackCheckBox.Data(:,2)={true};
                app.chkbox_batCell=app.int_batCell;
                app.prevState_allTracksCheckBox=true;
            elseif nChosen==nTracks & app.prevState_allTracksCheckBox==false & allTracks==false
                app.AllTracksCheckBox.Value=true;
                app.prevState_allTracksCheckBox=true;
                app.chkbox_batCell=app.int_batCell;
            elseif nChosen==nTracks & allTracks==false & app.prevState_allTracksCheckBox==true
                app.TrackCheckBox.Data(:,2)={false};
                app.prevState_allTracksCheckBox=false;
                app.chkbox_batCell=[];
            elseif nChosen==nTracks-1 & allTracks==true & app.prevState_allTracksCheckBox==true
                app.AllTracksCheckBox.Value=false;
                app.prevState_allTracksCheckBox=false;
                app.chkbox_batCell=app.int_batCell(usr_choice,:);
            elseif nChosen==nTracks-1 & allTracks==true & app.prevState_allTracksCheckBox==false
                app.TrackCheckBox.Data(:,2)={true};
                app.prevState_allTracksCheckBox=true;
                app.chkbox_batCell=app.int_batCell;
            elseif nChosen<nTracks & allTracks==true
                app.TrackCheckBox.Data(:,2)={true};
                app.prevState_allTracksCheckBox=true;
                app.chkbox_batCell=app.int_batCell;
            elseif nChosen<nTracks & allTracks==false
                app.AllTracksCheckBox.Value=false;
                app.prevState_allTracksCheckBox=false;
                app.chkbox_batCell=app.int_batCell(usr_choice,:);
            end
            % calculate summary data with chosen tracks
            if ~isempty(app.chkbox_batCell)
                app.summ=trackSummary_v1(app.chkbox_batCell);
            else
                app.summ=[];
            end
            % update the new summary in GUI
            updateTrackSumm(app);
            % check for figure existence
            chckBatFig(app);
            % Get turbine model
            try
                tb = app.vrTrans;
                % plot the chosen bat tracks
                resetVis(app);
                makeVis_v3(app,app.chkbox_batCell,app.summ,app.vis,tb);
            catch
                status(app,'ERROR: No tracks are selected',app.error);
                resetVis(app);
                updateVis(app.turbineSettingsApp,app.chkbox_batCell,app.summ);
            end
            %             plotVis_v1(app, app.model, app.chkbox_batCell, app.summ, 'add');
            % get file to export after user changes checkbox options for track selection
            app.export_batCell=app.chkbox_batCell;
        end

        % Value changed function: DayDropDown, InterferenceKnob, 
        % SetDropDown, SiteDropDown, TreatmentDropDown
        function getTrack(app, event)
            app.StatusLabel.Text = '';
            usr_day = app.DayDropDown.Value;
            usr_site=app.SiteDropDown.Value;
            usr_set=app.SetDropDown.Value;
            usr_trt=app.TreatmentDropDown.Value;
            %             fltspd=app.flight_speed;
            %             sin=app.sinuosity;
            %             sps=app.scaled_pixel;
            %             fhag=app.height_above_ground;
            %             fprt=app.pos_relative_turbines;
            % get user selected tracks
            [app.tmp_batCell,app.tmp_batInt,app.DayDropDown.Items,app.SiteDropDown.Items,app.SetDropDown.Items,app.TreatmentDropDown.Items]...
                =batCell_lookup_v2(usr_day,usr_site,usr_set,usr_trt,app.batCell);
            % check for interference
            track_int=app.tmp_batInt;
            int_yes_idx=find(track_int==1);
            int_no_idx=find(track_int==0);
            % check user chosen state of interference
            usr_int_value=app.InterferenceKnob.Value;
            % populate the track list based on user selected interference value
            switch usr_int_value % set batCell that is used to populate the track list
                case 'Off'
                    app.int_batCell=app.tmp_batCell; % update the batCell being fed to trackCheckBox
                    int_trackList=app.tmp_batCell(:,7); % update the trackList being displayed in the GUI
                    app.AllTracksCheckBox.Value=true;
                    app.prevState_allTracksCheckBox=true;
                case 'Yes'
                    app.int_batCell=app.tmp_batCell(int_yes_idx,:);
                    int_trackList=app.tmp_batCell(int_yes_idx,7);
                    app.AllTracksCheckBox.Value=true;
                    app.prevState_allTracksCheckBox=true;
                case 'No'
                    app.int_batCell=app.tmp_batCell(int_no_idx,:);
                    int_trackList=app.tmp_batCell(int_no_idx,7);
                    app.AllTracksCheckBox.Value=true;
                    app.prevState_allTracksCheckBox=true;
            end
            
            %             % Get track list and send to other callback
            %             app.trackList = int_trackList;
            % calculate track summary data
            app.summ=trackSummary_v1(app.int_batCell);
            % populate track list
            app.TrackCheckBox.Data=[int_trackList repmat({true},numel(int_trackList),1)];
            % update track summary data
            updateTrackSumm(app);
            % check for figure existence
            chckBatFig(app);
            % Get turbine model
            try
                tb = app.vrTrans;
                % plot figure - turbine and bat tracks
                resetVis(app);
                makeVis_v3(app,app.int_batCell,app.summ,app.vis,tb);
            catch
                status(app,'ERROR: Cannot draw bat tracks',app.error);
                resetVis(app);
                updateVis(app.turbineSettingsApp,app.int_batCell,app.summ);
            end
            % get file to export if the user hits export right after changing interference or day, site, set or treatment options
            app.export_batCell=app.int_batCell;
        end

        % Menu selected function: LoadexampleMenu
        function LoadexampleMenuSelected(app, event)
            app.StatusLabel.Text = '';
            %% get data files
            
            % Avoid changing the directory. Better to just locate the data
            cd B:\Projects\WindTurbines\BatVis3\example_data;
            dataFolder = pwd;
            allFile=dir(dataFolder); % get all files in the chosen directory
            allFileName={allFile.name}; % get file names only
            %             %% get data files
            %             dataFolder=uigetdir(path,'Select folder with Bat data'); % get path of the working folder
            %             figure(app.BatVisFig);
            %             if dataFolder==0 % user hits cancel
            %                 return
            %             end
            allFile=dir(dataFolder); % get all files in the chosen directory
            allFileName={allFile.name}; % get file names only
            
            % Inefficient coding here. Can just use dir('*xyzCI.csv');
            % get fullpath of all bat flat files
            batFilePattern='bat(\w+)xyzCI.tsv'; % file pattern to be checked
            idx_batFile=regexp(allFileName,batFilePattern); % compare pattern with allFiles
            idx_batFile=find(~cellfun(@isempty,idx_batFile)); % find the idx of bat files
            batFile=allFile(idx_batFile); % bat file struct
            
            % check for bat files
            if isempty(batFile)
                status(app,'No Bat CI tracks found, please check source folder and try again');
                return
            end
            
            %% get bat cell containing date, site, set, treatment and track data
            % make waitbar for file import
            % check if waitbar already exists
            wtbar_chck=findobj('type','figure','name','Importing Bat tracks...');
            if ~isempty(wtbar_chck)
                close(wtbar_chck);
            end
            
            status(app,'Loading example files... (please wait)',app.normal);
            
            % display message showing number of bat files
            nBatFile=numel(batFile);
            wtbar=waitbar(0,sprintf('Found %d Bat files to import...',nBatFile),'name',...
                'Importing Bat tracks...');
            
            % disable app buttons
            app.LoadDataMenu.Enable='off';
            
            % Get Bat 3D tracks, rotate relative to turbine and place in
            % cell array
            [app.show_logFile_makeBatCell,app.logFile_makeBatCell,app.batCell]=makeBatCell_v3(batFile,wtbar);
            
            % Delete wait bar
            delete(wtbar);
            
            % re-enable app buttons
            app.LoadDataMenu.Enable='on';
            app.SaveMenu.Enable='on';
            app.ExportDataMenu.Enable='on';
            app.QuitMenu.Enable='on';
           
            %% display items in drop down menus and tracks
            allDays=unique(app.batCell(:,2));
            all_opt={'all'};
            app.DayDropDown.Items=[all_opt;allDays]; % display all days with all option
            app.DayDropDown.Enable='on';
            allSites=unique(app.batCell(:,3));
            app.SiteDropDown.Items=[all_opt;allSites];
            app.SiteDropDown.Enable='on';
            allSets=unique(app.batCell(:,4));
            app.SetDropDown.Items=[all_opt;allSets];
            app.SetDropDown.Enable='on';
            allTreatments=unique(app.batCell(:,5));
            app.TreatmentDropDown.Items=[all_opt;allTreatments];
            app.TreatmentDropDown.Enable='on';
            allTracks=app.batCell(:,7); % changed from allTrack=unique(app.batCell(:,7)); 20180701 - Pranav Khandelwal
            app.TrackCheckBox.ColumnFormat={'char','logical'};
            app.TrackCheckBox.ColumnEditable=[false true];
            allTracks=[allTracks];
            app.TrackCheckBox.Data=[allTracks repmat({true},numel(allTracks),1)];
            app.TrackCheckBox.Enable='on';
            
            % let the entire batCell be the initial tmp_batCell for first time selection
            app.tmp_batCell=app.batCell;
            
            % thus the int_batCell which is fed into the trackCheckBox selection also becomes the initial tmp_batCell
            app.int_batCell=app.batCell;
            
            % re-enable the interference button and allTrack selector button
            app.InterferenceKnob.Enable='on';
            app.AllTracksCheckBox.Enable='on';
            app.AllTracksCheckBox.Value=true;
            app.prevState_allTracksCheckBox=app.AllTracksCheckBox.Value;
            
            %% track summary
            app.TrackLength.Enable='on';
            app.GroundSpeed.Enable='on';
            app.Altitude.Enable='on';
            app.MaxCentForce.Enable='on';
            app.MeanCentForce.Enable='on';
            app.BladeDist.Enable='on';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Track summary
            %app.FlightSpeed.Enable='on'; % Flight Speed
            app.Sinuosity.Enable='on'; % Sinuosity
            %app.ScaledPixelSize.Enable='on'; % Scaled pixel size
            %app.FlightHeightAboveGround.Enable='on'; % Flight height above ground
            %app.FlightPositionRelativeTurbines.Enable='on'; % Flight position relative to turbines
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % make figure for 3D plots
            app.vis=figure('Name','Bat Tracks v3','Color','white');
            set(app.vis,'NumberTitle','off','Tag','v3');
            mainAppPosition = app.BatVisFig.Position;
            app.vis.Position(1:2) = mainAppPosition(1:2)+[1147,80];
            
            % calculate track summary and update it
            app.summ=trackSummary_v1(app.tmp_batCell);
            updateTrackSumm(app);
            
            % Get turbine model
            try
                tb = app.vrTrans;
                % make figure for 3D plots
                makeVis_v3(app,app.tmp_batCell,app.summ,app.vis,tb);
            catch
                status(app,'ERROR: Cannot draw bat tracks',app.error);
            end
            status(app,'Bat Tracks is now open',app.normal);
        end

        % Menu selected function: ShowAdvancedSettingsMenu
        function ShowAdvancedSettingsMenuSelected(app, event)
            app.StatusLabel.Text = '';
            % Types of turbines:
            % MHI Vestas V164-9.5 MW: 262ft blades on a 345ft tower
            % GE 1.5 MW: 116ft blades on a 212ft tower
            % Vestas V90 1.8 MW: 148ft blades on a 262ft tower
            try
                % bring to front
                figure(app.turbineSettingsApp.AdvSettingsFig);
                % move
                mainAppPosition = app.BatVisFig.Position;
                app.turbineSettingsApp.AdvSettingsFig.Position(1:2)=mainAppPosition(1:2)+[883,0];
                status(app,'Advanced Settings app is now open',app.normal);
            catch
                app.turbineSettingsApp = TurbineAdvancedSettings(app,app.vis);
                status(app,'Advanced Settings app is opened again because the original had been deleted.',app.warning);
            end
            
            figure(app.BatVisFig);
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            app.StatusLabel.Text = '';
            %% get data files
            dataFolder=uigetdir(app.path,'Select folder with Bat data'); % get path of the working folder
            figure(app.BatVisFig);
            if dataFolder==0 % user hits cancel
                return
            end
            allFile=dir(dataFolder); % get all files in the chosen directory
            allFileName={allFile.name}; % get file names only
            % get fullpath of all bat flat files
            batFilePattern='bat(\w+)xyzCI.tsv'; % file pattern to be checked
            idx_batFile=regexp(allFileName,batFilePattern); % compare pattern with allFiles
            idx_batFile=find(~cellfun(@isempty,idx_batFile)); % find the idx of bat files
            batFile=allFile(idx_batFile); % bat file struct
            % check for bat files
            if isempty(batFile)
                status(app,'No Bat CI tracks found, please check source folder and try again',app.error);
                return
            end
            %% get bat cell containing date, site, set, treatment and track data
            % make waitbar for file import
            % check if waitbar already exists
            wtbar_chck=findobj('type','figure','name','Importing Bat tracks...');
            if ~isempty(wtbar_chck)
                close(wtbar_chck);
            end
            
            status(app,'Importing Bat files... (this may take a few minutes)',app.normal);
            % display message showing number of bat files
            nBatFile=numel(batFile);
            wtbar=waitbar(0,sprintf('Found %d Bat files to import...',nBatFile),'name',...
                'Importing Bat tracks...');
            % disable app buttons
            app.LoadDataMenu.Enable='off';
            [app.show_logFile_makeBatCell,app.logFile_makeBatCell,app.batCell]=makeBatCell_v3(batFile,wtbar);
            delete(wtbar); % delete wait bar at completion
            % re-enable app buttons
            app.LoadDataMenu.Enable='on';
            app.SaveMenu.Enable='on';
            app.ExportDataMenu.Enable='on';
            app.QuitMenu.Enable='on';
            %% display items in drop down menus and tracks
            allDays=unique(app.batCell(:,2));
            all_opt={'all'};
            app.DayDropDown.Items=[all_opt;allDays]; % display all days with all option
            app.DayDropDown.Enable='on';
            allSites=unique(app.batCell(:,3));
            app.SiteDropDown.Items=[all_opt;allSites];
            app.SiteDropDown.Enable='on';
            allSets=unique(app.batCell(:,4));
            app.SetDropDown.Items=[all_opt;allSets];
            app.SetDropDown.Enable='on';
            allTreatments=unique(app.batCell(:,5));
            app.TreatmentDropDown.Items=[all_opt;allTreatments];
            app.TreatmentDropDown.Enable='on';
            allTracks=app.batCell(:,7); % changed from allTrack=unique(app.batCell(:,7)); 20180701 - Pranav Khandelwal
            app.TrackCheckBox.ColumnFormat={'char','logical'};
            app.TrackCheckBox.ColumnEditable=[false true];
            allTracks=[allTracks];
            app.TrackCheckBox.Data=[allTracks repmat({true},numel(allTracks),1)];
            app.TrackCheckBox.Enable='on';
            % let the entire batCell be the initial tmp_batCell for first time selection
            app.tmp_batCell=app.batCell;
            % thus the int_batCell which is fed into the trackCheckBox selection also becomes the initial tmp_batCell
            app.int_batCell=app.batCell;
            % re-enable the interference button and allTrack selector button
            app.InterferenceKnob.Enable='on';
            app.AllTracksCheckBox.Enable='on';
            app.AllTracksCheckBox.Value=true;
            app.prevState_allTracksCheckBox=app.AllTracksCheckBox.Value;
            %% track summary
            app.TrackLength.Enable='on';
            app.GroundSpeed.Enable='on';
            app.Altitude.Enable='on';
            app.MaxCentForce.Enable='on';
            app.MeanCentForce.Enable='on';
            app.BladeDist.Enable='on';
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Track summary
            %app.FlightSpeed.Enable='on'; % Flight Speed
            app.Sinuosity.Enable='on'; % Sinuosity
            %app.ScaledPixelSize.Enable='on'; % Scaled pixel size
            %app.FlightHeightAboveGround.Enable='on'; % Flight height above ground
            %app.FlightPositionRelativeTurbines.Enable='on'; % Flight position relative to turbines
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % make figure for 3D plots
            app.vis=figure('Name','Bat Tracks v3','Color','white');
            set(app.vis,'NumberTitle','off','Tag','v3');
            mainAppPosition = app.BatVisFig.Position;
            app.vis.Position = mainAppPosition+[1147,0,0,-32];
            % calculate track summary and update it
            app.summ=trackSummary_v1(app.tmp_batCell);
            updateTrackSumm(app);
            % Get turbine model
            try
                tb = app.vrTrans;
                % make figure for 3D plots
                makeVis_v3(app,app.tmp_batCell,app.summ,app.vis,tb);
            catch
                status(app,'ERROR: Cannot draw bat tracks',app.error);
            end
            status(app,'Bat Tracks is now open');
        end

        % Menu selected function: ExportDataMenu
        function ExportDataMenuSelected(app, event)
            app.StatusLabel.Text = '';
            exp_tracks=app.export_batCell;
            exp_summ=app.summ;
            if isempty(exp_tracks)
                uiwait(msgbox('Please make a file selection','Export data','modal'));
                return
            end
            status(app,'Exporting user selected data...',app.normal);
            % make summary cell of all the data to be written
            summCell={};
            for i=1:size(exp_tracks,1)
                trackName=exp_tracks(i,2:5); % get date, set, site, treatment
                trackNum=exp_tracks(i,6); % get track id
                trackPts=exp_tracks(i,11);
                pts_x=num2cell(trackPts{:}(:,1)); pts_x(end-1:end)=[]; % get coordinates and match it to kinematics dimensions
                pts_y=num2cell(trackPts{:}(:,2)); pts_y(end-1:end)=[];
                pts_z=num2cell(trackPts{:}(:,3)); pts_z(end-1:end)=[];
                % get track metrics
                gs=num2cell(exp_summ(i).gs_inst);
                gs(end)=[];
                cf=num2cell(exp_summ(i).cf);
                alt=num2cell(exp_summ(i).alt);
                alt(end-1:end)=[];
                dist2hub=num2cell(exp_summ(i).dist2hub);
                dist2hub(end-1:end)=[];
                dist2bladePlane=num2cell(exp_summ(i).bladeDist);
                dist2bladePlane(end-1:end)=[];
                nEntries=length(gs);
                intBlade=num2cell(repmat(exp_summ(i).intBlade,nEntries,1));
                trackName=repmat(trackName,nEntries,1);
                trackNum=num2cell(repmat(trackNum,nEntries,1));
                frameNum=num2cell(1:nEntries)';
                tmpCell=[trackName trackNum frameNum pts_x pts_y pts_z gs cf alt dist2hub dist2bladePlane intBlade];
                summCell=[summCell;tmpCell];
            end
            % make table from cell with headers
            summTable=cell2table(summCell);
            summTable.Properties.VariableNames={'Day','Site','Set','Treatment','Track','Frame',...
                'pts_X','pts_Y','pts_Z','GroundSpeed','CentAcc','Altitude',...
                'HubDistance','BladePlaneDistance','Interference'};
            % Get the name of the file that the user wants to save.
            startingFolder = pwd;
            defaultFileName = fullfile(startingFolder, 'batVisv3_metrics_v1.csv');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Choose folder and specify file name');
            figure('Name','BatVis_v3');
            if baseFileName == 0
               	% User clicked the Cancel button.
               	return;
            end
            fullFileName = fullfile(folder, baseFileName);
            % write data to file
            fid = fopen(fullFileName, 'w');
            if fid ~= -1
                % write the data to file
                writetable(summTable,fullFileName);
                fclose(fid);
            else
                warningMessage = sprintf('Cannot open file:\n%s', fullFileName);
                uiwait(warndlg(warningMessage));
            end
            uiwait(msgbox('File saved!','Export data','modal'));
            status(app,[fullFileName,' saved successfully!']);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% save log file
            try
                % bring to front
                figure(app.saveLogFig);
                % move
                mainAppPosition = app.BatVisFig.Position;
                app.saveLogFig.Position(1:2)=mainAppPosition(1:2)+[10,200];
                status(app,'Save log app is now open',app.normal);
            catch
                % pop up dialog for import log file and check with user to save
                app.saveLogFig = importLog_v1(app.show_logFile_makeBatCell,app.logFile_makeBatCell);
                status(app,'Save log app is opened again because the original had been deleted.',app.warning);
            end
        end

        % Menu selected function: CloseSaveLogMenu
        function CloseSaveLogMenuSelected(app, event)
            chksaveFig=findobj(app.saveLogFig);
            if ~isempty(chksaveFig)
                close(app.saveLogFig);
            end
            
            figure(app.BatVisFig);
        end

        % Menu selected function: ShowBatTracksMenu
        function ShowBatTracksMenuSelected(app, event)
            try
                % bring to front
                figure(app.vis);
                % move
                mainAppPosition = app.BatVisFig.Position;
                app.vis.Position = mainAppPosition+[1147,0,0,-32];
                status(app,'Bat Tracks is now open',app.normal);
            catch
                status(app,'You need to load an example or initialize the data to open this figure.',app.error);
            end
            
            figure(app.BatVisFig);
        end

        % Menu selected function: KeyboardshortcutsMenu
        function KeyboardshortcutsMenuSelected(app, event)
            if strcmp(app.FileViewer.Visible,'on')
                app.FileViewer.Visible = 'off';
                app.TrackSummaryDataPanel.Title = 'Track Summary Data';
                return;
            else
                app.FileViewer.Visible = 'on';
                app.StatusLabel.Text = 'Press Ctrl+K to open/close the file viewer';
                app.TrackSummaryDataPanel.Title = 'File Viewer';
            end
            % This section is for reference only. The code block is
            % already in the startupFcn.
            %                 file = 'B:\Projects\WindTurbines\BatVis3\Data\shortcuts.txt';
            %                 str = extractFileText(file);
            %                 x = strfind(str, "start");
            %                 y = strfind(str, "end");
            %                 start = x(1);
            %                 fin = y(1);
            %                 file = extractBetween(str, start+5, fin-3);
            %                 app.FileViewer.Value = file;
            %                 app.FileViewer.FontSize = 20;
            %                 app.FileViewer.FontName = 'Times New Roman';
            %
            % Uses a message box to display the instructions.
            % I don't reccommend this because the formatting is weird and
            % it is hard to read.
            %             short = importdata('B:\Projects\WindTurbines\BatVis3\Data\shortcuts.txt');
            %             msgbox(short);
        end

        % Menu selected function: VersionMenu
        function VersionMenuSelected(app, event)
            if strcmp(app.VersionLabel.Visible,'on')
                app.VersionLabel.Visible = 'off';
            elseif strcmp(app.VersionLabel.Visible,'off')
                app.VersionLabel.Visible = 'on';
                version(app);
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            reallyQuit = questdlg('Are you sure you want to quit?','Quit','Yes','No','Save first','Yes');
            status(app,'Have you saved your project?',app.warning);
            if strcmp(reallyQuit,'Yes')
                % Stop animation
                app.stopAnimation = 0;
                % check if the bat track plot window is open
                chksaveFig=findobj(app.saveLogFig);
                chckFig=findobj(app.vis);
                try
                    AdvFig = figure(app.turbineSettingsApp.AdvSettingsFig);
                catch
                    AdvFig = findobj(app.turbineSettingsApp);
                end
                % close if open
                try
                    if ~isempty(chckFig) % all windows are open
                        try
                            close(app.vis);
                        catch
                            disp('Cannot close the bat visual fig');
                        end
                    end
                    if ~isempty(chksaveFig) % just the vis fig and the saveLogFig
                        try
                            close(app.saveLogFig);
                        catch
                            disp('Cannot close the save log fig');
                        end
                    end
                    if ~isempty(AdvFig) % just the vis fig and the advanced settings fig
                        try
                            app.turbineSettingsApp.delete;
                        catch
                            disp('Cannot close the advanced settings fig');
                        end
                    end
                catch
                    disp('Apps do not exist');
                end
                app.delete; % close the app window
                fprintf('\n');
                disp('Bat Turbine Visualization GUI v3 terminated successfully');
            elseif strcmp(reallyQuit,'No')
                return;
            elseif strcmp(reallyQuit,'Save first')
                SaveMenuSelected(app);
                pause(0.1);
                return;
            end
        end

        % Close request function: BatVisFig
        function quit(app, event)
            reallyQuit = questdlg('Are you sure you want to quit?','Quit','Yes','No','Save first','Yes');
            status(app,'Have you saved your project?',app.warning);
            if strcmp(reallyQuit,'Yes')
                % Stop animation
                app.stopAnimation = 0;
                % check if the bat track plot window is open
                chksaveFig=findobj(app.saveLogFig);
                chckFig=findobj(app.vis);
                try
                    AdvFig = figure(app.turbineSettingsApp.AdvSettingsFig);
                catch
                    AdvFig = findobj(app.turbineSettingsApp);
                end
                % close if open
                try
                    if ~isempty(chckFig) % all windows are open
                        try
                            app.vis.delete;
                        catch
                            disp('Cannot close the bat visual fig');
                        end
                    end
                    if ~isempty(chksaveFig) % just the vis fig and the saveLogFig
                        try
                            app.saveLogFig.delete;
                        catch
                            disp('Cannot close the save log fig');
                        end
                    end
                    if ~isempty(AdvFig) % just the vis fig and the advanced settings fig
                        try
                            app.turbineSettingsApp.delete;
                        catch
                            disp('Cannot close the advanced settings fig');
                        end
                    end
                catch
                    disp('Apps do not exist');
                end
                app.delete; % close the app window
                fprintf('\n');
                disp('Bat Turbine Visualization GUI v3 terminated successfully');
            elseif strcmp(reallyQuit,'No')
                return;
            elseif strcmp(reallyQuit,'Save first')
                SaveMenuSelected(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BatVisFig = uifigure('Visible', 'off');
            app.BatVisFig.Name = 'BatVis_v3';
            app.BatVisFig.CloseRequestFcn = createCallbackFcn(app, @quit, true);
            app.FileMenu = uimenu(app.BatVisFig);
            app.FileMenu.Text = 'File';
            app.LoadexampleMenu = uimenu(app.FileMenu);
            app.LoadexampleMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadexampleMenuSelected, true);
            app.LoadexampleMenu.Accelerator = 'l';
            app.LoadexampleMenu.Text = 'Load example';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Accelerator = 'i';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataMenuSelected, true);
            app.ExportDataMenu.Accelerator = 'e';
            app.ExportDataMenu.Text = 'Export Data';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Accelerator = 'q';
            app.QuitMenu.Text = 'Quit';
            app.OptionsMenu = uimenu(app.BatVisFig);
            app.OptionsMenu.Text = 'Options';
            app.MoreoptionsMenu = uimenu(app.OptionsMenu);
            app.MoreoptionsMenu.Text = 'More options';
            app.ShowAdvancedSettingsMenu = uimenu(app.MoreoptionsMenu);
            app.ShowAdvancedSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAdvancedSettingsMenuSelected, true);
            app.ShowAdvancedSettingsMenu.Accelerator = 't';
            app.ShowAdvancedSettingsMenu.Text = 'Show Advanced Settings';
            app.ShowBatTracksMenu = uimenu(app.MoreoptionsMenu);
            app.ShowBatTracksMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowBatTracksMenuSelected, true);
            app.ShowBatTracksMenu.Accelerator = 'b';
            app.ShowBatTracksMenu.Text = 'Show Bat Tracks';
            app.CloseSaveLogMenu = uimenu(app.MoreoptionsMenu);
            app.CloseSaveLogMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseSaveLogMenuSelected, true);
            app.CloseSaveLogMenu.Text = 'Close Save Log';
            app.BatTrackAnimationMenu = uimenu(app.MoreoptionsMenu);
            app.BatTrackAnimationMenu.Text = 'Bat Track Animation';
            app.ChangeBackgroundMenu = uimenu(app.OptionsMenu);
            app.ChangeBackgroundMenu.Text = 'Change Background';
            app.WhiteMenu = uimenu(app.ChangeBackgroundMenu);
            app.WhiteMenu.MenuSelectedFcn = createCallbackFcn(app, @WhiteMenuSelected, true);
            app.WhiteMenu.Checked = 'on';
            app.WhiteMenu.Text = 'White';
            app.LightGrayMenu = uimenu(app.ChangeBackgroundMenu);
            app.LightGrayMenu.MenuSelectedFcn = createCallbackFcn(app, @LightGrayMenuSelected, true);
            app.LightGrayMenu.Text = 'Light Gray';
            app.LightBlueMenu = uimenu(app.ChangeBackgroundMenu);
            app.LightBlueMenu.MenuSelectedFcn = createCallbackFcn(app, @LightBlueMenuSelected, true);
            app.LightBlueMenu.Text = 'Light Blue';
            app.DarkBlueMenu = uimenu(app.ChangeBackgroundMenu);
            app.DarkBlueMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkBlueMenuSelected, true);
            app.DarkBlueMenu.Text = 'Dark Blue';
            app.OliveMenu = uimenu(app.ChangeBackgroundMenu);
            app.OliveMenu.MenuSelectedFcn = createCallbackFcn(app, @OliveMenuSelected, true);
            app.OliveMenu.Text = 'Olive';
            app.AmberMenu = uimenu(app.ChangeBackgroundMenu);
            app.AmberMenu.MenuSelectedFcn = createCallbackFcn(app, @AmberMenuSelected, true);
            app.AmberMenu.Text = 'Amber';
            app.HelpMenu = uimenu(app.BatVisFig);
            app.HelpMenu.Text = 'Help';
            app.KeyboardshortcutsMenu = uimenu(app.HelpMenu);
            app.KeyboardshortcutsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyboardshortcutsMenuSelected, true);
            app.KeyboardshortcutsMenu.Accelerator = 'k';
            app.KeyboardshortcutsMenu.Text = 'Keyboard shortcuts';
            app.VersionMenu = uimenu(app.HelpMenu);
            app.VersionMenu.MenuSelectedFcn = createCallbackFcn(app, @VersionMenuSelected, true);
            app.VersionMenu.Accelerator = '0';
            app.VersionMenu.Text = 'Version';
            app.TrackSelector = uipanel(app.BatVisFig);
            app.TrackSelector.Title = 'Track Selector';
            app.DayDropDownLabel = uilabel(app.TrackSelector);
            app.DayDropDownLabel.Text = 'Day';
            app.DayDropDown = uidropdown(app.TrackSelector);
            app.DayDropDown.ValueChangedFcn = createCallbackFcn(app, @getTrack, true);
            app.SiteDropDownLabel = uilabel(app.TrackSelector);
            app.SiteDropDownLabel.Text = 'Site';
            app.SiteDropDown = uidropdown(app.TrackSelector);
            app.SiteDropDown.ValueChangedFcn = createCallbackFcn(app, @getTrack, true);
            app.SetDropDownLabel = uilabel(app.TrackSelector);
            app.SetDropDownLabel.Text = 'Set';
            app.SetDropDown = uidropdown(app.TrackSelector);
            app.SetDropDown.ValueChangedFcn = createCallbackFcn(app, @getTrack, true);
            app.TreatmentDropDownLabel = uilabel(app.TrackSelector);
            app.TreatmentDropDownLabel.Text = 'Treatment';
            app.TreatmentDropDown = uidropdown(app.TrackSelector);
            app.TreatmentDropDown.ValueChangedFcn = createCallbackFcn(app, @getTrack, true);
            app.TrackCheckBox = uitable(app.TrackSelector);
            app.TrackCheckBox.ColumnName = {'id'; 'Check'};
            app.TrackCheckBox.RowName = {''};
            app.TrackCheckBox.CellEditCallback = createCallbackFcn(app, @TrackCheckBoxCellSelection, true);
            app.TracksLabel = uilabel(app.TrackSelector);
            app.TracksLabel.Text = 'Tracks';
            app.InterferenceKnobLabel = uilabel(app.TrackSelector);
            app.InterferenceKnobLabel.Text = 'Interference';
            app.InterferenceKnob = uiknob(app.TrackSelector, 'discrete');
            app.InterferenceKnob.Items = {'Off', 'Yes', 'No'};
            app.InterferenceKnob.ValueChangedFcn = createCallbackFcn(app, @getTrack, true);
            app.InterferenceKnob.Value = 'Yes';
            app.AllTracksCheckBox = uicheckbox(app.TrackSelector);
            app.AllTracksCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrackCheckBoxCellSelection, true);
            app.AllTracksCheckBox.Text = 'All tracks';
            app.TrackSummaryDataPanel = uipanel(app.BatVisFig);
            app.TrackSummaryDataPanel.Title = 'Track Summary Data';
            app.TrackLengthmLabel = uilabel(app.TrackSummaryDataPanel);
            app.TrackLengthmLabel.Text = 'Track Length (m)';
            app.TrackLength = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.TrackLength.ValueDisplayFormat = '%.2f';
            app.TrackLength.Editable = 'off';
            app.MedGroundSpeedmsLabel = uilabel(app.TrackSummaryDataPanel);
            app.MedGroundSpeedmsLabel.Text = 'Med. Ground Speed (m/s)';
            app.GroundSpeed = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.GroundSpeed.ValueDisplayFormat = '%.2f';
            app.GroundSpeed.Editable = 'off';
            app.AvgAltitudeaboveHubmLabel = uilabel(app.TrackSummaryDataPanel);
            app.AvgAltitudeaboveHubmLabel.Text = 'Avg. Altitude above Hub (m)';
            app.Altitude = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.Altitude.ValueDisplayFormat = '%.2f';
            app.Altitude.Editable = 'off';
            app.MinDistancetoHubmLabel = uilabel(app.TrackSummaryDataPanel);
            app.MinDistancetoHubmLabel.Text = 'Min. Distance to Hub (m)';
            app.BladeDist = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.BladeDist.ValueDisplayFormat = '%.2f';
            app.BladeDist.Editable = 'off';
            app.MaxCentripetalForcems2Label = uilabel(app.TrackSummaryDataPanel);
            app.MaxCentripetalForcems2Label.Text = 'Max. Centripetal Force (m/s2)';
            app.MaxCentForce = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.MaxCentForce.ValueDisplayFormat = '%.2f';
            app.MaxCentForce.Editable = 'off';
            app.MedCentripetalForcems2Label = uilabel(app.TrackSummaryDataPanel);
            app.MedCentripetalForcems2Label.Text = 'Med. Centripetal Force  (m/s2)';
            app.MeanCentForce = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.MeanCentForce.ValueDisplayFormat = '%.2f';
            app.MeanCentForce.Editable = 'off';
            app.SinuosityradLabel = uilabel(app.TrackSummaryDataPanel);
            app.SinuosityradLabel.Text = 'Sinuosity (rad)';
            app.Sinuosity = uieditfield(app.TrackSummaryDataPanel, 'numeric');
            app.Sinuosity.ValueDisplayFormat = '%.2f';
            app.Sinuosity.Editable = 'off';
            app.Label = uilabel(app.TrackSummaryDataPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.FileViewer = uitextarea(app.TrackSummaryDataPanel);
            app.FileViewer.Visible = 'off';
            app.BatTurbineVisualizationLabel = uilabel(app.BatVisFig);
            app.BatTurbineVisualizationLabel.Text = 'Bat Turbine Visualization ';
            app.StatusLabel = uilabel(app.BatVisFig);
            app.StatusLabel.Text = 'Status';
            app.VersionLabel = uilabel(app.BatVisFig);
            app.VersionLabel.Text = 'Version';
            % Show the figure after all components are created
            app.BatVisFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = batVis_gui_v3_2019b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatVisFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatVisFig)
        end
    end
end