classdef opts_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DetectionOptionsPanel          matlab.ui.container.Panel
        MinobjectpixelsEditFieldLabel  matlab.ui.control.Label
        MinobjectpixelsEditField       matlab.ui.control.NumericEditField
        MaxobjectpixelsEditFieldLabel  matlab.ui.control.Label
        MaxobjectpixelsEditField       matlab.ui.control.NumericEditField
        FirstframeEditFieldLabel       matlab.ui.control.Label
        FirstframeEditField            matlab.ui.control.NumericEditField
        LastframeEditFieldLabel        matlab.ui.control.Label
        LastframeEditField             matlab.ui.control.NumericEditField
        TurbinedetectorDropDownLabel   matlab.ui.control.Label
        TurbinedetectorDropDown        matlab.ui.control.DropDown
        HeaderrowsDropDownLabel        matlab.ui.control.Label
        HeaderrowsDropDown             matlab.ui.control.DropDown
        ProcessorsEditFieldLabel       matlab.ui.control.Label
        ProcessorsEditField            matlab.ui.control.NumericEditField
        SavefilenameEditFieldLabel     matlab.ui.control.Label
        SavefilenameEditField          matlab.ui.control.EditField
        CheckButton                    matlab.ui.control.Button
        SensitivityDropDownLabel       matlab.ui.control.Label
        SensitivityDropDown            matlab.ui.control.DropDown
        TurbinebufferEditFieldLabel    matlab.ui.control.Label
        TurbinebufferEditField         matlab.ui.control.NumericEditField
        ChangeSaveFolderButton         matlab.ui.control.Button
        ChooseFileButton               matlab.ui.control.Button
        FilenameLabel                  matlab.ui.control.Label
        DTrackingoptionsPanel          matlab.ui.container.Panel
        MinTrackLengthEditFieldLabel   matlab.ui.control.Label
        MinTrackLengthEditField        matlab.ui.control.NumericEditField
        MaxgapLengthEditFieldLabel     matlab.ui.control.Label
        MaxgapLengthEditField          matlab.ui.control.NumericEditField
        MatchThresholdEditFieldLabel   matlab.ui.control.Label
        MatchThresholdEditField        matlab.ui.control.NumericEditField
        RunthruTrackerButton           matlab.ui.control.Button
        NoteHovermouseoverentryboxesformoreinfoLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
           % Store main app in property for CloseRequestFcn to use
           app.CallingApp = main_app;
            
            % Update UI with input values
            %app.opts = main_app.opts;
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.avi');
            movieFilename = fullfile(pathname, filename);
            app.CallingApp.opts.movieFilename{1} = movieFilename;
            app.CallingApp.v = VideoReader(movieFilename);
            
            app.CallingApp.opts.projectDir = pathname;  %Save data to same folder where video is stored
            duration = app.CallingApp.v.Duration;
            frame_rate = app.CallingApp.v.FrameRate;
            
            Total_frames = duration * frame_rate;
            
            % Set max frame range
            app.LastframeEditField.Value = Total_frames;
            app.LastframeEditField.Limits = [1, Total_frames];
            
            % Display filename
            app.FilenameLabel.Text = filename;
            app.SavefilenameEditField.Value = [filename(1:end-3), 'mat'];
            app.RunthruTrackerButton.Enable = 1;
        end

        % Button pushed function: RunthruTrackerButton
        function RunthruTrackerButtonPushed(app, event)
            % Update opts
            % app.CallingApp.opts = app.opts
            app.CallingApp.opts.minObjectArea = app.MinobjectpixelsEditField.Value;
            app.CallingApp.opts.maxObjectArea = app.MaxobjectpixelsEditField.Value;
            app.CallingApp.opts.startFrame    = app.FirstframeEditField.Value;
            app.CallingApp.opts.endFrame      = app.LastframeEditField.Value;
            
            if strcmp( app.TurbinedetectorDropDown.Value, app.TurbinedetectorDropDown.Items{1})
                app.CallingApp.opts.turbine_detector = 'edge';
            elseif strcmp( app.TurbinedetectorDropDown.Value, app.TurbinedetectorDropDown.Items{2})
                app.CallingApp.opts.turbine_detector = 'NN';
            elseif strcmp( app.TurbinedetectorDropDown.Value, app.TurbinedetectorDropDown.Items{3})
                app.CallingApp.opts.turbine_detector = 'None';
                app.CallingApp.opts.turbine = false;
            end
            if strcmp(app.HeaderrowsDropDown.Value, app.HeaderrowsDropDown.Items{1})
                app.CallingApp.opts.headerrows = 513:532;
            elseif strcmp(app.HeaderrowsDropDown.Value, app.HeaderrowsDropDown.Items{2})
                app.CallingApp.opts.headerrows = 1:25;
            else
                app.CallingApp.opts.headerrows = [];
            end
            
            app.CallingApp.opts.threads = app.ProcessorsEditField.Value;
            app.CallingApp.opts.saveFileName = app.SavefilenameEditField.Value;
            app.CallingApp.opts.minimumTrackLength = app.MinTrackLengthEditField.Value;
            app.CallingApp.opts.maximumGapLength = app.MaxgapLengthEditField.Value;
            app.CallingApp.opts.badMatchThreshold = app.MatchThresholdEditField.Value;
            app.CallingApp.opts.DetectionSensitivity = app.SensitivityDropDown.Value;
            app.CallingApp.opts.turbineBuffer = app.TurbinebufferEditField.Value;
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            core_info = evalc('feature(''numcores'')');
            % Check for parallel computing toolbox
            if license('test','Distrib_Computing_Toolbox')
                msgbox(['Parallel Computing Toolbox detected' newline core_info(1:34)])
            else
                msgbox(['Parallel Computing Toolbox NOT detected\r' newline core_info(1:34)])
            end
            
        end

        % Button pushed function: ChangeSaveFolderButton
        function ChangeSaveFolderButtonPushed(app, event)
            pathname = uigetdir([],'Choose directory where data will be saved');
            app.CallingApp.opts.projectDir = pathname;  %Save data to same folder where video is stored
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DetectionOptionsPanel = uipanel(app.UIFigure);
            app.DetectionOptionsPanel.Tooltip = {'This is the number of pixels around that detected turbine that is considered part of the turbine. Use higher values to help avoid false turbine detections. Reccomended value is 15 or up to 40 if viewing turibne from the monopole'};
            app.DetectionOptionsPanel.Title = 'Detection Options';
            app.MinobjectpixelsEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.MinobjectpixelsEditFieldLabel.Text = 'Min object pixels';
            app.MinobjectpixelsEditField = uieditfield(app.DetectionOptionsPanel, 'numeric');
            app.MinobjectpixelsEditField.Limits = [1 Inf];
            app.MinobjectpixelsEditField.Tooltip = {'Minimum acceptable object size in pixels'};
            app.MinobjectpixelsEditField.Value = 5;
            app.MaxobjectpixelsEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.MaxobjectpixelsEditFieldLabel.Text = 'Max object pixels';
            app.MaxobjectpixelsEditField = uieditfield(app.DetectionOptionsPanel, 'numeric');
            app.MaxobjectpixelsEditField.Limits = [100 Inf];
            app.MaxobjectpixelsEditField.Tooltip = {'Maximum acceptable object size in pixels'};
            app.MaxobjectpixelsEditField.Value = 500;
            app.FirstframeEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.FirstframeEditFieldLabel.Text = 'First frame';
            app.FirstframeEditField = uieditfield(app.DetectionOptionsPanel, 'numeric');
            app.FirstframeEditField.RoundFractionalValues = 'on';
            app.FirstframeEditField.ValueDisplayFormat = '%.0f';
            app.FirstframeEditField.Tooltip = {'First frame of the video that will be processed'};
            app.FirstframeEditField.Value = 1;
            app.LastframeEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.LastframeEditFieldLabel.Text = 'Last frame';
            app.LastframeEditField = uieditfield(app.DetectionOptionsPanel, 'numeric');
            app.LastframeEditField.RoundFractionalValues = 'on';
            app.LastframeEditField.ValueDisplayFormat = '%.0f';
            app.LastframeEditField.Tooltip = {'Last frame of the video that will be processed'};
            app.LastframeEditField.Value = 100;
            app.TurbinedetectorDropDownLabel = uilabel(app.DetectionOptionsPanel);
            app.TurbinedetectorDropDownLabel.Tooltip = {'Method for finding Wind Turbine. Options include edge detection'; ' two deep learning neural networks'; ' and none  if no turbine is present.'};
            app.TurbinedetectorDropDownLabel.Text = 'Turbine detector';
            app.TurbinedetectorDropDown = uidropdown(app.DetectionOptionsPanel);
            app.TurbinedetectorDropDown.Items = {'Edge detector', 'Neural Network 1', 'No Turbine'};
            app.TurbinedetectorDropDown.Value = 'Edge detector';
            app.HeaderrowsDropDownLabel = uilabel(app.DetectionOptionsPanel);
            app.HeaderrowsDropDownLabel.Text = 'Header rows';
            app.HeaderrowsDropDown = uidropdown(app.DetectionOptionsPanel);
            app.HeaderrowsDropDown.Items = {'513:532 (Flir A65)', '1:25 (Axis)', 'None'};
            app.HeaderrowsDropDown.Tooltip = {'Header rows where information about the frame is saved.'};
            app.HeaderrowsDropDown.Value = '513:532 (Flir A65)';
            app.ProcessorsEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.ProcessorsEditFieldLabel.Text = 'Processors';
            app.ProcessorsEditField = uieditfield(app.DetectionOptionsPanel, 'numeric');
            app.ProcessorsEditField.Tooltip = {'Computer processors used for analaysis. Set to 1 if you want to see results of processing as you go. The parrallel processing toolbox needs to be installed for using additional processors. Use check button to find available number of processors.'};
            app.ProcessorsEditField.Value = 1;
            app.SavefilenameEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.SavefilenameEditFieldLabel.Text = 'Save filename';
            app.SavefilenameEditField = uieditfield(app.DetectionOptionsPanel, 'text');
            app.SavefilenameEditField.Tooltip = {'filename where data will be saved.'};
            app.CheckButton = uibutton(app.DetectionOptionsPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.SensitivityDropDownLabel = uilabel(app.DetectionOptionsPanel);
            app.SensitivityDropDownLabel.Text = 'Sensitivity';
            app.SensitivityDropDown = uidropdown(app.DetectionOptionsPanel);
            app.SensitivityDropDown.Items = {'High', 'Medium', 'Low'};
            app.SensitivityDropDown.Tooltip = {'Sensitivity of the detection algorithm.'; 'High will detect more objects (and noise)'; ' whereas Low will reject more objects and noise'};
            app.SensitivityDropDown.Value = 'High';
            app.TurbinebufferEditFieldLabel = uilabel(app.DetectionOptionsPanel);
            app.TurbinebufferEditFieldLabel.Text = 'Turbine buffer';
            app.TurbinebufferEditField = uieditfield(app.DetectionOptionsPanel, 'numeric');
            app.TurbinebufferEditField.Value = 10;
            app.ChangeSaveFolderButton = uibutton(app.DetectionOptionsPanel, 'push');
            app.ChangeSaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeSaveFolderButtonPushed, true);
            app.ChangeSaveFolderButton.Text = 'Change Save Folder';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose Video File';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'Filename';
            app.DTrackingoptionsPanel = uipanel(app.UIFigure);
            app.DTrackingoptionsPanel.Title = '2D Tracking options';
            app.MinTrackLengthEditFieldLabel = uilabel(app.DTrackingoptionsPanel);
            app.MinTrackLengthEditFieldLabel.Text = 'Min Track Length';
            app.MinTrackLengthEditField = uieditfield(app.DTrackingoptionsPanel, 'numeric');
            app.MinTrackLengthEditField.Tooltip = {'Minimum number of points required for a track (Tracks shorter than this length will be deleted)'};
            app.MinTrackLengthEditField.Value = 5;
            app.MaxgapLengthEditFieldLabel = uilabel(app.DTrackingoptionsPanel);
            app.MaxgapLengthEditFieldLabel.Text = 'Max gap Length';
            app.MaxgapLengthEditField = uieditfield(app.DTrackingoptionsPanel, 'numeric');
            app.MaxgapLengthEditField.Tooltip = {'Maximum number of frames between detections in a track'};
            app.MaxgapLengthEditField.Value = 5;
            app.MatchThresholdEditFieldLabel = uilabel(app.DTrackingoptionsPanel);
            app.MatchThresholdEditFieldLabel.Text = 'Match Threshold';
            app.MatchThresholdEditField = uieditfield(app.DTrackingoptionsPanel, 'numeric');
            app.MatchThresholdEditField.Tooltip = {'maximum acceptable number of pixels between the predicted location of the track and a detection to be accepted as part of the track. Use higher values when there are more variable frame rates or faster-moving objects.'};
            app.MatchThresholdEditField.Value = 20;
            app.RunthruTrackerButton = uibutton(app.UIFigure, 'push');
            app.RunthruTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @RunthruTrackerButtonPushed, true);
            app.RunthruTrackerButton.Enable = 'off';
            app.RunthruTrackerButton.Text = 'Run thruTracker';
            app.NoteHovermouseoverentryboxesformoreinfoLabel = uilabel(app.UIFigure);
            app.NoteHovermouseoverentryboxesformoreinfoLabel.Text = 'Note: Hover mouse over entry boxes for more info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opts_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end