classdef thruTracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        File                      matlab.ui.container.Menu
        New2DDetectionMenu        matlab.ui.container.Menu
        ReprocessDetectionsMenu   matlab.ui.container.Menu
        Make3DMenu                matlab.ui.container.Menu
        LoadProjectMenu           matlab.ui.container.Menu
        SaveProjectMenu           matlab.ui.container.Menu
        ExportDataMenu            matlab.ui.container.Menu
        CloseMenu                 matlab.ui.container.Menu
        ToolsMenu                 matlab.ui.container.Menu
        IntrinsicCalibrationMenu  matlab.ui.container.Menu
        Make3DCalibrationMenu     matlab.ui.container.Menu
        TrackSelectorMenu         matlab.ui.container.Menu
        TrackCounterMenu          matlab.ui.container.Menu
        MakeTrackVideoMenu        matlab.ui.container.Menu
        MakeDetectionsVideoMenu   matlab.ui.container.Menu
        ControlsPanel             matlab.ui.container.Panel
        DisplayImagesLabel        matlab.ui.control.Label
        DisplayImagesSwitch       matlab.ui.control.Switch
        nextFrameButton           matlab.ui.control.Button
        previousFrameButton       matlab.ui.control.Button
        FrameEditField_2Label     matlab.ui.control.Label
        FrameEditField            matlab.ui.control.NumericEditField
        TrackDropDownLabel        matlab.ui.control.Label
        TrackDropDown             matlab.ui.control.DropDown
        ClassDropDown             matlab.ui.control.DropDown
        SelectClassesButton       matlab.ui.control.Button
        ProgressTextAreaLabel     matlab.ui.control.Label
        ProgressTextArea          matlab.ui.control.TextArea
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        videoFilename   % full path and filename for video to be analyzed
        v               % VideoReader Object for current video file
        dets            % 2D detection data
        opts            % Options for processing
        current_cam     % Current cam for processing and visualization
        last_i          % last frame index
        last_frame      % last image
        last_frame_sub  % last background-subtracted image
        opticFlow       % Image optic flow
        se1             % strel disk of size 3
        se3             % strel disk of size 1
        seBuffer            % strel disk of size 10
        net             % network for segmenting turbine image
        track_opts      % track video options
        roi             % rectangle for counting exits
    end
    
    properties (Access = private)
        Property14 % Description
    end
    
    methods (Access = public)
               
        function plotDetections(app, frame, i, Centroid, turbine_pixels, foreground)            % frame is image from current frame i
            % frame can either be provided or read from video if left empty
            
            if nargin ==1 % Image data not provided
                i = app.FrameEditField.Value;
                frame = [];
            end
            
            %check to see if frame given is within processed range
            if i < app.opts.startFrame | i > app.opts.endFrame
                app.ProgressTextArea.Value = 'Frame given is outside processed range';
                return
            end
            
            %check to see if video frame is specified
            if isempty(frame)
                %check to see if video is at i
                if isstruct(app.v) % cine format
                    frame = cineRead(app.opts.movieFilename{1},i);
                    frame = frame./max(frame(:));
                    frame = imadjust(frame);  %Crop low and high pixel values
                    frame = uint8(frame*256);     %Convert to 8-bit
                else  %Video Reader format

                    if round(app.v.currentTime * app.v.frameRate) ~= i
                        app.v.currentTime = i/app.v.frameRate;
                    end
                    if ~app.v.hasFrame()
                        app.ProgressTextArea.Value = 'Video File has no more frames';
                        return
                    end
                    frame = app.v.readFrame();
                    frame(app.opts.headerrows,:,:) = [];      %cut header; should be specified in opts file
                    frame_rgb = frame;      %Keep a copy of original frame
                    frame = mean(frame,3);
                end
            else
                if size(frame,3)> 1
                    frame = mean(frame,3);
                end
            end
            cla(app.UIAxes)
            cla(app.UIAxes2)
            imagesc(frame,'Parent',app.UIAxes);
            axis(app.UIAxes, 'tight')
            hold(app.UIAxes,'on');
            hold(app.UIAxes2,'on');
            set(app.UIAxes2,'YDir','reverse');
            % Plot detections within this frame
            if ~isempty(app.dets.uvData{1})
                frame_dets = app.dets.uvData2{i};     %Detection within this frame
                
                if ~isempty(frame_dets)
                    frame_dets = frame_dets{1};
                end
                
                if ~isempty(frame_dets)
                    app.UIAxes2.Visible = false;
                    
                    % Plot 2D detections
                    plot(app.dets.uvData2{i}{1}(:,1),app.dets.uvData2{i}{1}(:,2),'ro','Parent',app.UIAxes)  
                     
                else
                    %cla(app.UIAxes2,'reset')
                    image([],'Parent',app.UIAxes2)
                    colorbar(app.UIAxes2,'off')
                end
            end
            
            % Plot background-subtracted image
            if strcmp(app.DisplayImagesSwitch.Value,'On')

                    if i == app.last_i + 1  %Frame just advanced by 1
                        if strcmp(app.opts.detector, 'background subtraction')  %Use Background detection
                            frame_sub = fcns.getFrameSub(frame,app.last_frame, app.opts,app.se3);
                            frame_sub(frame_sub<0) = 0;
                            imagesc(frame_sub,'Parent',app.UIAxes2, [0, 8]);
                            axis(app.UIAxes2, 'tight')
                            colorbar(app.UIAxes2)
                            
                            if ~isempty(frame_dets)
                                plot(app.dets.uvData2{i}{1}(:,1),app.dets.uvData2{i}{1}(:,2),'ro','Parent',app.UIAxes2) 
                            end
                            app.last_frame_sub = frame_sub;     %Saving last 
                        elseif strcmp(app.opts.detector, 'optic flow')  %Use optic flow for background detection
                            %flow = estimateFlow(app.opticFlow,frame);
                            %frame_sub = imgaussfilt(flow.Magnitude,2,'FilterSize',3);
                            
                            flow = frame - app.last_frame;
                            flow(flow<0) = 0;
                            frame_sub = imgaussfilt(flow,2,'FilterSize',3);
                            
                            if strcmp(app.opts.turbine_detector, 'edge')
                                turbine_pixels = fcns.getTurbineShim(frame,app.se1,app.seBuffer);
                            elseif strcmp(app.opts.turbine_detector,'NN')
                                %app.net = load("TurbineNetwork1.mat")
                                turbine_pixels = fcns.getTurbineNN(frame_rgb,app.se3,app.net);
                            else
                                disp('No turbine detector specified')
                                turbine_pixels = [];
                            end
                            if ~isempty(turbine_pixels)
                               frame_sub(turbine_pixels) = 0;
                            end
                            imagesc(frame_sub,'Parent',app.UIAxes2);
                            axis(app.UIAxes2, 'tight')
                            set(app.UIAxes2,'YDir','reverse');
                            %Plot detections
                            if ~isempty(frame_dets)
                                plot(app.dets.uvData2{i}{1}(:,1),app.dets.uvData2{i}{1}(:,2),'ro','Parent',app.UIAxes2) 
                            end
                            
                            %if ~isempty(app.dets.uvData2{i-1}{1})
                                %plot(app.dets.uvData2{i-1}{1}(:,1),app.dets.uvData2{i-1}{1}(:,2),'yo','Parent',app.UIAxes2)
                                colorbar(app.UIAxes2)
                            %end
                        end
                    else
                        frame_sub = [];
                        app.last_frame_sub = [];
                        if strcmp(app.opts.detector,'optic flow')
                            %Initialize Optic Flow
                            app.opticFlow = opticalFlowHS;
                            app.opticFlow.MaxIteration = 1;
                            app.opticFlow.Smoothness = 1;
                            flow = estimateFlow(app.opticFlow,frame);
                            
                        end
                    end
            end
            
            % Change this to first check if seletect track is within frame
            % range
            if isfield(app.dets,'tracks2D')
                % find track(s) for current frame
                if ~isempty(app.dets.uvdata)
                    idx = find(app.dets.uvdata(:,1) ==i); %Currently only finding first point in frame
                else
                    idx = [];
                end
                
                if ~isempty(idx)
                    idx(idx>length(app.dets.tracks2D)) = [];
                    plot_tracks = app.dets.tracks2D(idx);
                    %app.TrackDropDown.Value = idx; %Nullified this because
                    %it didn't have proper checks in place
                    
                    % Plot selected Track
                    for j = 1:length(plot_tracks)
                    
                    idx = find (app.dets.tracks2D==plot_tracks(j));
                        if ~isempty(idx)
                            if plot_tracks(j)==app.TrackDropDown.Value
                                plot(app.dets.uvdata(idx,2),app.dets.uvdata(idx,3),'.-r','Parent',app.UIAxes)
                            else
                                plot(app.dets.uvdata(idx,2),app.dets.uvdata(idx,3),'.-w','Parent',app.UIAxes)
                            end
                        end
                    end
                end
            end
            
            if nargin > 3   % display pseudocolor image if data is available
                
                %Make pseudocolor image with turbine and detections
                if ~isempty(turbine_pixels)
                    bc = double(repmat(foreground,1,1,3));
                    foo = zeros([size(frame,1), size(frame,2)]);  
                    if app.opts.turbine
                        foo(turbine_pixels) = 0.5;
                    end
                    bc(:,:,1) = foo;
                    bc(:,:,2) = foo;
                    imshow(bc,'Parent',app.UIAxes2);
                else
                    imagesc(foreground,'Parent',app.UIAxes2)
                end
                hold(app.UIAxes2,'on');
                axis(app.UIAxes2, 'tight');
                if ~isempty(Centroid)
                    plot(Centroid(:,1),Centroid(:,2),'ro','Parent',app.UIAxes)
                    plot(Centroid(:,1),Centroid(:,2),'ro','Parent',app.UIAxes2)
                end
            end
            app.last_i = i;
            app.last_frame = frame;

            drawnow
            pause(0.01)
        end
        
        function opts = load_opts(app)
            %Load Opts file
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = mfilename();
            end
            
            opts.currentDir = currentDir;
            
            %[filename, pathname] =uigetfile('*.m','Choose opts file', currentDir);
            %filetext = fileread(fullfile(pathname,filename));
            
            filetext = fileread(fullfile(currentDir,'opts_data.m'));
            
            % Use regular expressions to run opts file
            expr = '[^\n]*[^\n]*';
            matches = regexp(filetext,expr,'match');
            for i = 1:length(matches)
                eval(matches{i});
            end
        
            %run(fullfile(pathname,filename)); %Load opts file
            
        end
        
        function results = Count_Bats(app, edges, count_app)
            rect = app.roi.Position;
            [exit_list] = count_exits(app.dets, rect, edges);
            1;
            exit_count = numel(find(exit_list(:,2)==1));
            Reentry_count = numel(find(exit_list(:,2)==-1));
            
            delete(count_app);
            delete(app.roi);
            answer = questdlg([num2str(exit_count) ' exits counted' newline...
                               num2str(Reentry_count) ' Re-entries counted' newline...
                               'Would you like to save counts to a spreadsheet?']);
            if strcmp(answer,'Yes')
                exit_table = table(exit_list(:,1),exit_list(:,2));
                exit_table.Properties.VariableNames{1} = 'frame number';
                exit_table.Properties.VariableNames{2} = 'Exit (1) or Re-entry (-1)';
                writetable(exit_table,[app.opts.movieFilename{1}(1:end-4),'_exit_counts.csv']);
                msgbox(['Data saved to: ' [app.opts.movieFilename{1}(1:end-4),'_exit_counts.csv']]);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function NewButtonPushed(app, event)
        %Reset values    
        app.ProgressTextArea.Value = '';
            
        %Load Opts file
        if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
        else % MATLAB mode.
        currentDir = pwd;
        end
        
        %[filename, pathname] =uigetfile('*.m','Choose opts file', currentDir);
        
        %filetext = fileread(fullfile(pathname,filename));
        filetext = fileread(fullfile(currentDir,'opts_data.m'));
        
        opts.projectDir = currentDir;  % Project directory is where opts file is stored
        
        % Use regular expressions to run opts file
        expr = '[^\n]*[^\n]*';
        matches = regexp(filetext,expr,'match');
        for i = 1:length(matches)
            eval(matches{i});
        end
        
        %run(fullfile(pathname,filename)); %Load opts file
        app.opts = opts;
        
        % Call opts app
        opts_app_handle = opts_app(app);
        waitfor(opts_app_handle);
        
        app.dets = [];      %delete previous detections
        app.dets.uvData{1} = [];
        app.se1 = strel('disk',1);
        app.se3 = strel('disk',3);
        app.seBuffer = strel('disk',app.opts.turbineBuffer);
        
        %load('TurbineNetwork2.mat');
        try
            NN = load('Binary_gray_4th_round_20200218.mat');
        % Now using this for binary classification network
            app.net = NN.net;
        catch
            app.net = [];
        end
        
        % Avoiding using this for deployed version becuase of error not
        % having deep learning toolbox installed
        %app.net = importKerasNetwork('Binary_gray_4th_round_20200218.h5');
        
        videoFrames = round(app.v.FrameRate*app.v.Duration);
        if videoFrames < app.opts.endFrame || isnan(app.opts.endFrame)
            app.opts.endFrame = videoFrames;
        end
        
        %Set frame rate
        app.opts.FrameRate = app.v.FrameRate;
            
        % Detect objects in video   
        app.dets = fcns.BatDetect_v1_2(app,app.opts);


            dets = app.dets;
            opts = app.opts;
            projectname = opts.saveFileName;
            save(fullfile(opts.projectDir, projectname),'dets', 'opts');
            
            % Process Tracks
            [app.dets,app.opts] = fcns.UVto3D_v1(app.dets, app.opts,app.opts.threads); %Last number is number of threads for parrallel processing
            
            % Update results of tracking
            text = app.ProgressTextArea.Value;
            text{length(text)+1} = ['Search Complete.' num2str(length(app.dets.uvdata)) ' detections made'];
            app.ProgressTextArea.Value = text;
            
            %Save data
            dets = app.dets;
            opts = app.opts;
            
            %projectname = inputdlg('Name your project file');
            %projectname = projectname{1};
            %projectname = [projectname,'.mat'];
            projectname = opts.saveFileName;
            
            
            save(fullfile(opts.projectDir, projectname),'dets', 'opts');
            text = app.ProgressTextArea.Value;
            text{length(text)+1} = ['Data saved'];
            app.ProgressTextArea.Value = text;
            
            % Load track numbers
            app.TrackDropDown.Items = cellstr(num2str(unique(dets.tracks2D)));
            app.TrackDropDown.ItemsData = unique(dets.tracks2D);
            app.TrackDropDown.Enable = true;
            
            %app.ProgressTextArea.Value = ['Project ' filename ' loaded'];
            app.FrameEditField.Value = app.opts.startFrame;
        end

        % Button pushed function: nextFrameButton
        function nextFrameButtonPushed(app, event)
            app.FrameEditField.Value = app.FrameEditField.Value+1;
            plotDetections(app,[],app.FrameEditField.Value);
        end

        % Button pushed function: previousFrameButton
        function previousFrameButtonPushed(app, event)
            app.FrameEditField.Value = app.FrameEditField.Value-1;
            plotDetections(app,[],app.FrameEditField.Value);
        end

        % Callback function
        function PlayButtonValueChanged(app, event)
            if app.PlayButton.Value
                app.PlayButton.Text = 'Pause';
                while app.FrameEditField.Value < app.opts.endFrame
                    %pause(0.001)
                    
                    if app.PlayButton.Value == 0
                        break
                    end
                    app.FrameEditField.Value = app.FrameEditField.Value + 1;
                    plotDetections(app,[],app.FrameEditField.Value);
                    pause(0.001)
                    drawnow
                end
                app.PlayButton.Text = 'Play';
            end
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            i = app.FrameEditField.Value;
            plotDetections(app,[],i);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    previousFrameButtonPushed(app)
                case 'rightarrow'
                    nextFrameButtonPushed(app)
                case 'uparrow'
                    value = app.TrackDropDown.Value;
                    items_data = app.TrackDropDown.ItemsData;
                    idx = find(items_data==value);
                    if idx >= 2
                        app.TrackDropDown.Value = items_data(idx-1)
                        TrackDropDownValueChanged(app);
                    end
                case 'downarrow'
                    value = app.TrackDropDown.Value;
                    items_data = app.TrackDropDown.ItemsData;
                    idx = find(items_data==value);
                    if idx +1 <= length(items_data)
                        app.TrackDropDown.Value = items_data(idx+1)
                        TrackDropDownValueChanged(app);
                    end
                    
                case 'o'
                    LoadProjectMenuSelected(app)
                case 'p'
                    if app.PlayButton.Value == 1
                        app.PlayButton.Value = 0;
                        app.PlayButton.Text = 'Pause'
                    else
                        app.PlayButton.Value = 1;
                        app.PlayButton.Text = 'Play'
                    end
                    PlayButtonValueChanged(app)
                case {'1', '2' , '3' , '4' , '5' , '6' , '7' , '8' , '9' , '0'} 
                    keynum = str2num(key);  %Number of the key that was pressed
                    
                    %Check to see if trackID field has been made
                    if ~isfield(app.dets,'trackID')
                        app.dets.trackID = zeros(max(app.dets.tracks2D),1)
                    end
                    % Save value of number press to trackIDs
                    app.dets.trackID(app.TrackDropDown.Value) = keynum;
                    
                    % Set dropdown
                    Items = app.ClassDropDown.Items;
                    app.ClassDropDown.Value = Items{keynum+1};
                    %Find selected track
                    
                    Track_num = app.TrackDropDown.Value;
            end
        end

        % Value changed function: TrackDropDown
        function TrackDropDownValueChanged(app, event)
            track_num = app.TrackDropDown.Value;
            % Find frame of beginning of track;
            idx = find(app.dets.tracks2D==track_num,1);
            track_frame = app.dets.uvdata(idx,1);
            app.FrameEditField.Value = track_frame;
            plotDetections(app);
            
            %Lookup trackID if it exists
            if isfield(app.dets,'trackID')
                % app.dets.trackID = nan(max(app.dets.tracks2D),1)
                trackID = app.dets.trackID(track_num);
            else
                trackID = 0;
            end
           
            %Set Class DropDown
            Items = app.ClassDropDown.Items;
            app.ClassDropDown.Value = Items{trackID+1};
        end

        % Callback function
        function SaveButtonPushed(app, event)
            dets = app.dets;
            opts = app.opts;
            savename = inputdlg('Enter save filename', 'Filename prompt', 10, {opts.saveFileName})
            save(fullfile(opts.projectDir, savename{1}),'dets', 'opts');
        end

        % Menu selected function: New2DDetectionMenu
        function New2DDetectionMenuSelected(app, event)
            %Reset values    
        %{
            answer = questdlg(['NOTE: DATA MAY NOT SAVE if you do not have rights to the folder where you are saving data! You may' ...
            ' want to test on a smaller section of recording before continuing. If an error occurs close thruTracker and re-open'...
            ' by right clicking and selecting Run as Adminstrator. Do you want to continue?'],...
    	    ' Dialogue box',...
            'Yes', 'No','Yes');
       % waitfor(questdlg);
            
        if strcmp(answer,'No')
            msgbox('Run canceled because of user input')
            return
        end
        %}
        app.ProgressTextArea.Value = '';
        
        if strcmp(event.Source.Text,'Re-process Detections')
            app.opts.event = 'Re-process Detections';
        else
        
            %Load Opts file
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = mfilename();
            end
            
            opts.currentDir = currentDir;
            
            %[filename, pathname] =uigetfile('*.m','Choose opts file', currentDir);
            %filetext = fileread(fullfile(pathname,filename));
            
            filetext = fileread(fullfile(currentDir,'opts_data.m'));
            
            % Use regular expressions to run opts file
            expr = '[^\n]*[^\n]*';
            matches = regexp(filetext,expr,'match');
            for i = 1:length(matches)
                eval(matches{i});
            end
            app.opts = load_opts(app);
            %run(fullfile(pathname,filename)); %Load opts file
            
            opts_list = load('opts_list.mat');
            app.opts.opts_list = opts_list.opts_list;
            app.opts.lastpath = opts_list.lastpath;
            app.opts.event = 'new detection';
        end
        
        % Call opts app
        opts_app_handle = opts_app(app);
        waitfor(opts_app_handle);
        
        if ~app.opts.thrutracker_ran
            %Operation was canceled
            return
        end
        app.dets = [];      %delete previous detections
        app.dets.uvData{1} = [];
        app.se1 = strel('disk',1);
        app.se3 = strel('disk',3);
        app.seBuffer = strel('disk',app.opts.turbineBuffer);
        
        %load('TurbineNetwork2.mat');
        NN = load('Binary_gray_4th_round_20200218.mat');
        app.net = NN.net;
    
        % app.net = importKerasNetwork('Binary_gray_4th_round_20200218.h5');
        
        videoFrames = round(app.v.FrameRate*app.v.Duration);
        if videoFrames < app.opts.endFrame || isnan(app.opts.endFrame)
            app.opts.endFrame = videoFrames;
        end
        
        %Set frame rate
        app.opts.FrameRate = app.v.FrameRate;
            
        if app.opts.threads > 1 %Run in parrallel
            app.ProgressTextArea.Value = {'Processing images in parallel, see Console for progress updates. No images will be shown during parallel processing'};
            [app.dets.uvData{1}] = fcns.BatDetect_par_shim(app.v,app.opts, app);
        else
            [app.dets.uvData{1}] = fcns.BatDetect_v1_2(app.v,app,app.opts);   
        end
          
            dets = app.dets;
            opts = app.opts;
            projectname = opts.saveFileName;
            save(fullfile(opts.projectDir, projectname),'dets', 'opts');
            
            % Process Tracks
            [app.dets,app.opts] = fcns.UVto3D_v1(app.dets, app.opts,app.opts.threads); %Last number is number of threads for parrallel processing
            
            % Update results of tracking
            text = app.ProgressTextArea.Value;
            text{length(text)+1} = ['Search Complete.' num2str(length(app.dets.uvdata)) ' detections made'];
            app.ProgressTextArea.Value = text;
            app.dets.trackID = zeros(max(app.dets.tracks2D),1);
            
            %Save data
            dets = app.dets;
            opts = app.opts;
            
            %projectname = inputdlg('Name your project file');
            %projectname = projectname{1};
            %projectname = [projectname,'.mat'];
            projectname = opts.saveFileName;
            
            
            save(fullfile(opts.projectDir, projectname),'dets', 'opts');
            text = app.ProgressTextArea.Value;
            text{length(text)+1} = ['Data saved'];
            app.ProgressTextArea.Value = text;
            
            % Load track numbers
            app.TrackDropDown.Items = cellstr(num2str(unique(dets.tracks2D)));
            app.TrackDropDown.ItemsData = unique(dets.tracks2D);
            app.TrackDropDown.Enable = true;
            
            %app.ProgressTextArea.Value = ['Project ' filename ' loaded'];
            app.FrameEditField.Value = double(app.opts.startFrame);
            app.ReprocessDetectionsMenu.Enable = true;
        end

        % Menu selected function: LoadProjectMenu
        function LoadProjectMenuSelected(app, event)
            %Load Opts file
        if isdeployed % Stand-alone mode.
            [status, result] = system('path');
            currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
        else % MATLAB mode.
        currentDir = pwd;
        end
        
        if isfield(app.opts,'projectDir')  
            [filename, pathname] = uigetfile('*.mat','Choose project file',app.opts.projectDir);
        else
            [filename, pathname] = uigetfile('*.mat','Choose project file',currentDir);
        end
        data = load(fullfile(pathname,filename));
        app.dets = data.dets;
        app.opts = data.opts;
        app.current_cam = 1;   %current camera index 
        app.se1 = strel('disk',1);
        app.se3 = strel('disk',3);
        app.seBuffer = strel('disk', app.opts.turbineBuffer);
        try
            load('TurbineNetwork2.mat');
            app.net = net;
        catch
            disp('Error loading classification network; this option will not be available');
           app.net = []; 
        end
        
        try
            app.v = VideoReader(app.opts.movieFilename{app.current_cam});    %Load videoreader object
            filename  = app.opts.movieFilename{app.current_cam};
        catch
            [filename, pathname] = uigetfile('*.avi','Movie file not found. Select movie file');
            app.v = VideoReader(fullfile(pathname, filename));    %Load videoreader object
        end
        
        %Check to make sure end frame isn't greater than file size
        videoFrames = round(app.v.FrameRate*app.v.Duration);
        if videoFrames < app.opts.endFrame
            app.opts.endFrame = videoFrames;
        end
        
        % Load track numbers
        app.TrackDropDown.Items = cellstr(num2str(unique(app.dets.tracks2D)));
        app.TrackDropDown.ItemsData = unique(app.dets.tracks2D);
        app.TrackDropDown.Enable = true;
        
        app.ProgressTextArea.Value = ['Project ' filename ' loaded'];
        app.FrameEditField.Value = double(app.opts.startFrame);
        
        %Plot track
        TrackDropDownValueChanged(app);
            
        end

        % Menu selected function: SaveProjectMenu
        function SaveProjectMenuSelected(app, event)
            dets = app.dets;
            opts = app.opts;
            projectname = opts.saveFileName;
            save(fullfile(opts.projectDir, projectname),'dets', 'opts');
            msgbox(['Data saved to: ' fullfile(opts.projectDir, projectname)]);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            UIFigureCloseRequest(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = questdlg('Request to close thruTracker. Would you like save classification data?',...
                'close dialogue',...
                'Yes','No','Cancel','Yes')
        switch answer
            case 'Yes'
                SaveProjectMenuSelected(app)
                delete(app)
            case 'No'
                delete(app)
            case 'Cancel'
                
        end
        
            
        end

        % Menu selected function: ExportDataMenu
        function ExportDataMenuSelected(app, event)
            answer = questdlg('Which data would you like to export?',...
                'Export dialogue box',...
                'Track data','UV Data','Both','Track data')
                
                % Export Track data
                % Column 1: Track number
                % Column 2: Start Frame
                % Column 3: End Frame
                % Column 4: Track ID
                % Column 5: Add more here including pixel speed, sinuosity,
                % etc..
               if strcmp(answer,'Track data') | strcmp(answer,'Both')
                   data_table = fcns.TrackCalcs(app.dets);
                   fooname = fullfile([app.opts.projectDir,app.opts.saveFileName(1:end-4),'_trackdata.csv']);
                   [savename, pathname]  = uiputfile(fooname);
                   writetable(data_table,fullfile(pathname, savename));
                   msgbox(['Data saved to ', savename]);
               end
               
               if strcmp(answer,'Track data') | strcmp(answer,'UV Data')
                   data = app.dets.uvdata; 
                   data(:,4) = app.dets.tracks2D;
                   data_table = array2table(data);
                   data_table.Properties.VariableNames{1} = 'Frame Number';
                   data_table.Properties.VariableNames{2} = 'U';
                   data_table.Properties.VariableNames{3} = 'V';
                   data_table.Properties.VariableNames{4} = 'Track Number';
                   fooname = fullfile([app.opts.projectDir,app.opts.saveFileName(1:end-4),'_UVdata.csv']);
                   [savename, pathname]  = uiputfile(fooname);
                   writetable(data_table,fullfile(pathname, savename));
                   msgbox(['Data saved to ', savename]);
               end
                
        end

        % Button pushed function: SelectClassesButton
        function SelectClassesButtonPushed(app, event)
            ChooseTracks(app);
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
            value = app.ClassDropDown.Value;
            % Save value of number press to trackIDs
            % Set dropdown
            Items = app.ClassDropDown.Items;
            Value_num = find(strcmp(Items,value))-1;
          
            app.dets.trackID(app.TrackDropDown.Value) = Value_num;
        end

        % Callback function
        function Classify2DtracksMenuSelected(app, event)
            if ~isfield(app.dets,'track_data')
                app.dets.track_data = fcns.TrackCalcs(app.dets);
                
                %As an example, I'm classifying any track with sinuosity <
                %0.8 or net movement < 2 pixels/frame as "other"--9
                1;
                idx = find (app.dets.track_data.Sinuosity < 0.7 | app.dets.track_data.Net_Movement_speed < 1.5); 
                app.dets.trackID(idx) = 9;
                msgbox([num2str(length(idx)) ' tracks auto-classified as 9-Other']);
            end
        end

        % Menu selected function: MakeTrackVideoMenu
        function MakeTrackVideoMenuSelected(app, event)
            
            %Get Current Track XY coordinates and frames
            trackN = app.TrackDropDown.Value;
            idx = find(app.dets.tracks2D==trackN);
            frames = app.dets.uvdata(idx,1);
            
            % Get User inputs
            %Side length of square around detected image
            prompt = {'Enter image width/height in pixels:','Video format (avi or mp4)', 'first frame', 'last frame'};
            dlgtitle = 'Input';
            dims = [1 35];
            if ~isempty(app.track_opts)
                definput = app.track_opts;
            else
                definput = {'128','avi',num2str(frames(1)),num2str(frames(end))};
            end
            
            inputs = inputdlg(prompt,dlgtitle,dims,definput);
            app.track_opts = inputs; %Save for later
            width = str2num(inputs{1});
            vid_format = inputs{2};
            st_frame = str2num(inputs{3});
            end_frame = str2num(inputs{4});
            
            %Interpolate frame range
            framesi = st_frame:end_frame;
            XYs = app.dets.uvdata(idx,2:3);
            
            
            % Smooth to avoid choppy videos
            XYs_smoothed(:,1) = smooth(XYs(:,1),11,'rloess');
            XYs_smoothed(:,2) = smooth(XYs(:,2),11,'rloess');
            
            XYs_smoothed = interp1(frames, XYs_smoothed, framesi,'linear','extrap');
            
            XYs_smoothed = round(XYs_smoothed);
           
            %Load video
            if isstruct(app.v)
                frameRate = app.v.FrameRate;
            else
                vid = VideoReader(app.opts.movieFilename{1});
                frameRate = vid.FrameRate;
            end
            
            %Create new folder for track videos if needed
            [pathname, filename] = fileparts(app.opts.movieFilename{1})
            
            if exist([pathname '\trackVideos\']) == 0
                mkdir([pathname '\trackVideos\']);
            end


            %Create video for writing
            if strcmp(vid_format,'avi')
                write_vid = VideoWriter([pathname '\trackVideos\' filename...
                'Track' num2str(trackN) '.avi'], 'Uncompressed AVI');
            elseif strcmp(vid_format,'mp4')
                write_vid = VideoWriter([pathname '\trackVideos\' filename...
                'Track' num2str(trackN) '.mp4'], 'MPEG-4');
            else
                msgbox('incorrect video format specified; canceling operation')
                return
            end
         
            open(write_vid);
            
            %Adjust detection values for padding
            XYtop_left = XYs_smoothed - width/2;
            
            % Make sure crop is within the image;
            XYtop_left(XYtop_left<1) = 1;
            
            
            % Read an extra frame to get frame size;
            if isstruct(app.v)
                frame = cineRead(app.opts.movieFilename{1},1)
                frame = frame./max(frame(:));
                low_high = stretchlim(frame,[0.1,0.9995]);
                %low_high(2) = 1; % don't crop high end
            else
                frame = readFrame(vid);
                %Set to beginning of track 
                vid.CurrentTime = (framesi(1)-1)/frameRate;
            end
            
            %XY and frame size are reversed
            foo = XYtop_left(:,2);
            foo(foo> size(frame,1)-width) = size(frame,1)-width;
            XYtop_left(:,2) = foo;
 
            foo2 = XYtop_left(:,1);
            foo2(foo2> size(frame,2)-width) = size(frame,2)-width
            XYtop_left(:,1) = foo2;
            
            f = waitbar(0,'Making video...');
            for i = 1:length(framesi)
                waitbar(i/length(framesi),f)
                %Get next frame
                if isstruct(app.v)
                    frame = cineRead(app.opts.movieFilename{1},framesi(i));
                    frame = frame./max(frame(:));
                    frame = imadjust(frame,low_high);  %Crop low and high pixel values
                    frame = uint8(frame*256);     %Convert to 8-bit
                else
                    frame = readFrame(vid);
                end
                %Zero pad the image... changed to go in from image
                %edge. 
                %frame_padded = zeros(size(frame,1)+width,size(frame,2)+width,'uint8');
                %frame_padded(width/2+1:width/2+size(frame,1), width/2+1:width/2+size(frame,2)) = frame(:,:,1);
                
                %Extract cropped image
                try
                    frame_crop = frame(XYtop_left(i,2):XYtop_left(i,2)+width,...
                                XYtop_left(i,1):XYtop_left(i,1)+width,1);
                    % Need to code this in better; currently 
                    %frame_crop = flipud(frame_crop);
                catch
                    1;
                end
                writeVideo(write_vid,frame_crop);     
            end
            close(f);
            close(write_vid);
            framesi = framesi';
            % Save crop information
            save([pathname '\trackVideos\' filename...
                'Track' num2str(trackN) '.mat'],'XYtop_left','framesi');
            
            msgbox('Video Saved')
            
        end

        % Menu selected function: Make3DMenu
        function Make3DMenuSelected(app, event)
            opts3D_app_handle = opts_3D(app);
            waitfor(opts3D_app_handle);
        end

        % Menu selected function: Make3DCalibrationMenu
        function Make3DCalibrationMenuSelected(app, event)
            simpleWand_handle = simpleWand();
            waitfor(simpleWand_handle);
        end

        % Callback function
        function Visualize3DTracksMenuSelected(app, event)
            bat_vis_handle = batVis_gui_v3_2019b;
        end

        % Menu selected function: IntrinsicCalibrationMenu
        function IntrinsicCalibrationMenuSelected(app, event)
            Therm_app = ThermalCamCalibator2;
            waitfor(Therm_app);
        end

        % Menu selected function: TrackSelectorMenu
        function TrackSelectorMenuSelected(app, event)
            % Make a new figure with first frame of video and draw tracks
            TrackSelectorApp = TrackSelector(app);
            waitfor(TrackSelectorApp);

        end

        % Menu selected function: ReprocessDetectionsMenu
        function ReprocessDetectionsMenuSelected(app, event)
            New2DDetectionMenuSelected(app,event);
        end

        % Menu selected function: TrackCounterMenu
        function TrackCounterMenuSelected(app, event)
            % Draw rectangle
            m = msgbox('Draw a rectangle around the exit area')
            waitfor(m);
            app.roi = drawrectangle(app.UIAxes);
            CountAppDialogue(app);        
            
        end

        % Menu selected function: MakeDetectionsVideoMenu
        function MakeDetectionsVideoMenuSelected(app, event)
            MakeDetectionsVideo();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.New2DDetectionMenu = uimenu(app.File);
            app.New2DDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @New2DDetectionMenuSelected, true);
            app.New2DDetectionMenu.Text = 'New 2D Detection';
            app.ReprocessDetectionsMenu = uimenu(app.File);
            app.ReprocessDetectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReprocessDetectionsMenuSelected, true);
            app.ReprocessDetectionsMenu.Enable = 'off';
            app.ReprocessDetectionsMenu.Text = 'Re-process Detections';
            app.Make3DMenu = uimenu(app.File);
            app.Make3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Make3DMenuSelected, true);
            app.Make3DMenu.Text = 'Make 3D Tracks';
            app.LoadProjectMenu = uimenu(app.File);
            app.LoadProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProjectMenuSelected, true);
            app.LoadProjectMenu.Text = 'Load Project';
            app.SaveProjectMenu = uimenu(app.File);
            app.SaveProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectMenuSelected, true);
            app.SaveProjectMenu.Text = 'Save Project';
            app.ExportDataMenu = uimenu(app.File);
            app.ExportDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataMenuSelected, true);
            app.ExportDataMenu.Text = 'Export Data';
            app.CloseMenu = uimenu(app.File);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.IntrinsicCalibrationMenu = uimenu(app.ToolsMenu);
            app.IntrinsicCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @IntrinsicCalibrationMenuSelected, true);
            app.IntrinsicCalibrationMenu.Text = 'Intrinsic Calibration';
            app.Make3DCalibrationMenu = uimenu(app.ToolsMenu);
            app.Make3DCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @Make3DCalibrationMenuSelected, true);
            app.Make3DCalibrationMenu.Text = 'Make 3D Calibration';
            app.TrackSelectorMenu = uimenu(app.ToolsMenu);
            app.TrackSelectorMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackSelectorMenuSelected, true);
            app.TrackSelectorMenu.Text = 'Track Selector';
            app.TrackCounterMenu = uimenu(app.ToolsMenu);
            app.TrackCounterMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackCounterMenuSelected, true);
            app.TrackCounterMenu.Text = 'Track Counter';
            app.MakeTrackVideoMenu = uimenu(app.ToolsMenu);
            app.MakeTrackVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeTrackVideoMenuSelected, true);
            app.MakeTrackVideoMenu.Text = 'Make Track Video';
            app.MakeDetectionsVideoMenu = uimenu(app.ToolsMenu);
            app.MakeDetectionsVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeDetectionsVideoMenuSelected, true);
            app.MakeDetectionsVideoMenu.Text = 'Make Detections Video';
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.DisplayImagesLabel = uilabel(app.ControlsPanel);
            app.DisplayImagesLabel.Text = 'Display Images?';
            app.DisplayImagesSwitch = uiswitch(app.ControlsPanel, 'slider');
            app.DisplayImagesSwitch.Value = 'On';
            app.nextFrameButton = uibutton(app.ControlsPanel, 'push');
            app.nextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @nextFrameButtonPushed, true);
            app.nextFrameButton.Tooltip = {'Advance a frame (Keyboard shortcut: right arrow)'};
            app.nextFrameButton.Text = '>';
            app.previousFrameButton = uibutton(app.ControlsPanel, 'push');
            app.previousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @previousFrameButtonPushed, true);
            app.previousFrameButton.Tooltip = {'Go back a frame (Keyboard shortcut: left arrow)'};
            app.previousFrameButton.Text = '<';
            app.FrameEditField_2Label = uilabel(app.ControlsPanel);
            app.FrameEditField_2Label.Text = 'Frame';
            app.FrameEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.FrameEditField.Limits = [1 Inf];
            app.FrameEditField.RoundFractionalValues = 'on';
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 1;
            app.TrackDropDownLabel = uilabel(app.ControlsPanel);
            app.TrackDropDownLabel.Enable = 'off';
            app.TrackDropDownLabel.Text = 'Track';
            app.TrackDropDown = uidropdown(app.ControlsPanel);
            app.TrackDropDown.Items = {'1'};
            app.TrackDropDown.ValueChangedFcn = createCallbackFcn(app, @TrackDropDownValueChanged, true);
            app.TrackDropDown.Enable = 'off';
            app.TrackDropDown.Value = '1';
            app.ClassDropDown = uidropdown(app.ControlsPanel);
            app.ClassDropDown.Items = {'0-Unclassified', '1-Bat', '2-Bird', '3-Insect', '4-Bat or Bird', '5-Animal', '6-Airplane', '7-Turbine tip', '8-Cloud', '9-Other'};
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.ClassDropDown.Value = '0-Unclassified';
            app.SelectClassesButton = uibutton(app.ControlsPanel, 'push');
            app.SelectClassesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClassesButtonPushed, true);
            app.SelectClassesButton.Text = 'Select Classes';
            app.ProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProgressTextAreaLabel.Text = 'Progress:';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thruTracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end